classdef Treefallsoftware_v2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image2                          matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        InitialSetupTab                 matlab.ui.container.Tab
        TranslationModelButtonGroup     matlab.ui.container.ButtonGroup
        InputLabel_Translation          matlab.ui.control.Label
        DecayingTranslation             matlab.ui.control.RadioButton
        ConstantTranslation             matlab.ui.control.RadioButton
        WindFieldModelButtonGroup       matlab.ui.container.ButtonGroup
        ModifiedSullivan                matlab.ui.control.RadioButton
        InputLabel_WindField            matlab.ui.control.Label
        Sullivan                        matlab.ui.control.RadioButton
        BurgersRott                     matlab.ui.control.RadioButton
        Rankine                         matlab.ui.control.RadioButton
        ExcelSheetNumber                matlab.ui.control.NumericEditField
        ENDINGPOINTLATITUDEEditField_2Label_2  matlab.ui.control.Label
        ObservedTreefallPatternPanel    matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        InputTornadoPathPanel           matlab.ui.container.Panel
        EndLON_EditField_TC             matlab.ui.control.NumericEditField
        ENDINGPOINTLONGITUDEEditField_2Label  matlab.ui.control.Label
        EndLAT_EditField_TC             matlab.ui.control.NumericEditField
        ENDINGPOINTLATITUDEEditField_2Label  matlab.ui.control.Label
        StartLON_EditField_TC           matlab.ui.control.NumericEditField
        STARTINGPOINTLONGITUDEEditField_2Label  matlab.ui.control.Label
        StartLAT_EditField_TC           matlab.ui.control.NumericEditField
        STARTINGPOINTLATITUDEEditField_2Label  matlab.ui.control.Label
        InputLabel_TC                   matlab.ui.control.Label
        InputTreefallCoordinatesPanel   matlab.ui.container.Panel
        InputCompleteButton             matlab.ui.control.Button
        TreefallDirectionSwitch         matlab.ui.control.Switch
        TreefallDirectionSwitchLabel    matlab.ui.control.Label
        InputLabel_Tree_2               matlab.ui.control.Label
        TreeDir_EditField               matlab.ui.control.EditField
        TREEFALLDIRECTIONLabel          matlab.ui.control.Label
        EndLON_EditField_Tree           matlab.ui.control.EditField
        ENDINGPOINTLONGITUDEEditField_3Label  matlab.ui.control.Label
        EndLAT_EditField_Tree           matlab.ui.control.EditField
        ENDINGPOINTLATITUDEEditField_3Label  matlab.ui.control.Label
        StartLON_EditField_Tree         matlab.ui.control.EditField
        STARTINGPOINTLONGITUDEEditField_3Label  matlab.ui.control.Label
        StartLAT_EditField_Tree         matlab.ui.control.EditField
        STARTINGPOINTLATITUDEEditField_3Label  matlab.ui.control.Label
        InputLabel_Tree                 matlab.ui.control.Label
        CurrentFileEditField            matlab.ui.control.EditField
        CurrentFileEditFieldLabel       matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        CatalogMethodTab                matlab.ui.container.Tab
        CatalogMethod                   matlab.ui.container.TabGroup
        SimulatedTreefallPatternTab     matlab.ui.container.Tab
        Catalog_Vc_lb                   matlab.ui.control.NumericEditField
        LBLabel_25                      matlab.ui.control.Label
        Catalog_Vc_ub                   matlab.ui.control.NumericEditField
        UBLabel_25                      matlab.ui.control.Label
        LBLabel_24                      matlab.ui.control.Label
        Catalog_phi_lb                  matlab.ui.control.NumericEditField
        UBLabel_24                      matlab.ui.control.Label
        Catalog_phi_ub                  matlab.ui.control.NumericEditField
        LBLabel_23                      matlab.ui.control.Label
        Catalog_rmax_lb                 matlab.ui.control.NumericEditField
        UBLabel_23                      matlab.ui.control.Label
        Catalog_rmax_ub                 matlab.ui.control.NumericEditField
        LBLabel_22                      matlab.ui.control.Label
        Catalog_alpha_lb                matlab.ui.control.NumericEditField
        UBLabel_22                      matlab.ui.control.Label
        Catalog_alpha_ub                matlab.ui.control.NumericEditField
        LBLabel_21                      matlab.ui.control.Label
        Catalog_gmax_lb                 matlab.ui.control.NumericEditField
        UBLabel_21                      matlab.ui.control.Label
        Catalog_gmax_ub                 matlab.ui.control.NumericEditField
        LBLabel_20                      matlab.ui.control.Label
        Catalog_Vtrans_lb               matlab.ui.control.NumericEditField
        UBLabel_20                      matlab.ui.control.Label
        Catalog_Vtrans_ub               matlab.ui.control.NumericEditField
        Catalog_NextPatternsButton      matlab.ui.control.Button
        Catalog_PreviousPatternsButton  matlab.ui.control.Button
        StartEstimationButton_Catalog   matlab.ui.control.Button
        Catalog_InputLabel_Vc           matlab.ui.control.Label
        Catalog_InputLabel_phi          matlab.ui.control.Label
        Catalog_InputLabel_RMW          matlab.ui.control.Label
        Catalog_InputLabel_alpha        matlab.ui.control.Label
        Catalog_InputLabel_Gmax         matlab.ui.control.Label
        Catalog_InputLabel_Vtrans       matlab.ui.control.Label
        Catalog_InputLabel_Bounds       matlab.ui.control.Label
        UIAxes6_1                       matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes6_3                       matlab.ui.control.UIAxes
        UIAxes6_4                       matlab.ui.control.UIAxes
        UIAxes6_5                       matlab.ui.control.UIAxes
        BestMatchParametersTab          matlab.ui.container.Tab
        ExportTableButton_Catalog       matlab.ui.control.Button
        ImportParametersCatalogButton   matlab.ui.control.Button
        TransectNum_Catalog             matlab.ui.control.NumericEditField
        TransectEditFieldLabel          matlab.ui.control.Label
        UITable_Catalog                 matlab.ui.control.Table
        TransectMethodTab               matlab.ui.container.Tab
        TransectMethod                  matlab.ui.container.TabGroup
        SimulatedTreefallPatternTab_2   matlab.ui.container.Tab
        Trans_Vc_gs                     matlab.ui.control.NumericEditField
        gsLabel_4                       matlab.ui.control.Label
        Trans_Vc_lb                     matlab.ui.control.NumericEditField
        LBLabel_29                      matlab.ui.control.Label
        Trans_Vc_ub                     matlab.ui.control.NumericEditField
        UBEditFieldLabel_6              matlab.ui.control.Label
        Trans_phi_gs                    matlab.ui.control.NumericEditField
        gsLabel_3                       matlab.ui.control.Label
        Trans_phi_lb                    matlab.ui.control.NumericEditField
        LBLabel_28                      matlab.ui.control.Label
        Trans_phi_ub                    matlab.ui.control.NumericEditField
        UBEditFieldLabel_5              matlab.ui.control.Label
        Trans_rmax_gs                   matlab.ui.control.NumericEditField
        gsLabel_2                       matlab.ui.control.Label
        Trans_rmax_lb                   matlab.ui.control.NumericEditField
        LBLabel_27                      matlab.ui.control.Label
        Trans_rmax_ub                   matlab.ui.control.NumericEditField
        UBEditFieldLabel_4              matlab.ui.control.Label
        Trans_alpha_gs                  matlab.ui.control.NumericEditField
        gsLabel                         matlab.ui.control.Label
        Trans_alpha_lb                  matlab.ui.control.NumericEditField
        LBLabel_26                      matlab.ui.control.Label
        Trans_alpha_ub                  matlab.ui.control.NumericEditField
        UBEditFieldLabel_3              matlab.ui.control.Label
        Trans_gmax_gs                   matlab.ui.control.NumericEditField
        gsEditFieldLabel_3              matlab.ui.control.Label
        Trans_gmax_lb                   matlab.ui.control.NumericEditField
        LBEditFieldLabel_2              matlab.ui.control.Label
        Trans_gmax_ub                   matlab.ui.control.NumericEditField
        UBEditFieldLabel_2              matlab.ui.control.Label
        Trans_Vtrans_gs                 matlab.ui.control.NumericEditField
        gsEditFieldLabel_2              matlab.ui.control.Label
        Trans_Vtrans_lb                 matlab.ui.control.NumericEditField
        LBEditFieldLabel                matlab.ui.control.Label
        Trans_Vtrans_ub                 matlab.ui.control.NumericEditField
        UBEditFieldLabel                matlab.ui.control.Label
        Trans_NextPatternsButton        matlab.ui.control.Button
        Trans_PreviousPatternsButton    matlab.ui.control.Button
        StartEstimationButton_Trans     matlab.ui.control.Button
        Trans_InputLabel_Vc             matlab.ui.control.Label
        Trans_InputLabel_phi            matlab.ui.control.Label
        Trans_InputLabel_RMW            matlab.ui.control.Label
        Trans_InputLabel_alpha          matlab.ui.control.Label
        Trans_InputLabel_Gmax           matlab.ui.control.Label
        Trans_InputLabel_Vtrans         matlab.ui.control.Label
        Trans_InputLabel_Bounds         matlab.ui.control.Label
        UIAxes7_1                       matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes7_3                       matlab.ui.control.UIAxes
        UIAxes7_4                       matlab.ui.control.UIAxes
        UIAxes7_5                       matlab.ui.control.UIAxes
        BestMatchParametersTab_2        matlab.ui.container.Tab
        ExportTableButton_Trans         matlab.ui.control.Button
        ImportParametersTransButton     matlab.ui.control.Button
        TransectNum_Trans               matlab.ui.control.NumericEditField
        TransectEditFieldLabel_2        matlab.ui.control.Label
        UITable_Trans                   matlab.ui.control.Table
        AnalyticalMethodTab             matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        BestFitParameterEstimationTab   matlab.ui.container.Tab
        Anal_phi_lb                     matlab.ui.control.NumericEditField
        LBEditField_5Label              matlab.ui.control.Label
        Anal_phi_ub                     matlab.ui.control.NumericEditField
        UBEditField_5Label              matlab.ui.control.Label
        Anal_rmax_lb                    matlab.ui.control.NumericEditField
        LBEditField_4Label              matlab.ui.control.Label
        Anal_rmax_ub                    matlab.ui.control.NumericEditField
        UBEditField_4Label              matlab.ui.control.Label
        Anal_alpha_lb                   matlab.ui.control.NumericEditField
        LBEditField_3Label              matlab.ui.control.Label
        Anal_alpha_ub                   matlab.ui.control.NumericEditField
        UBEditField_3Label              matlab.ui.control.Label
        Anal_gmax_lb                    matlab.ui.control.NumericEditField
        LBEditField_2Label              matlab.ui.control.Label
        Anal_gmax_ub                    matlab.ui.control.NumericEditField
        UBEditField_2Label              matlab.ui.control.Label
        Anal_eta_lb                     matlab.ui.control.NumericEditField
        LBEditFieldLabel_3              matlab.ui.control.Label
        Anal_eta_ub                     matlab.ui.control.NumericEditField
        UBEditFieldLabel_7              matlab.ui.control.Label
        TreefallPatternPanel            matlab.ui.container.Panel
        UIAxes_GC_Obs                   matlab.ui.control.UIAxes
        BestFitDiagraminLBetaSpacePanel  matlab.ui.container.Panel
        Anal_PreviousPatternButton      matlab.ui.control.Button
        Anal_NextPatternButton          matlab.ui.control.Button
        UIAxes5                         matlab.ui.control.UIAxes
        NumIteration                    matlab.ui.control.NumericEditField
        NumberofIterationLabel          matlab.ui.control.Label
        ObservedTreefallPatternTypeSwitch  matlab.ui.control.Switch
        ObservedTreefallPatternTypeLabel  matlab.ui.control.Label
        Anal_InputLabel_phi             matlab.ui.control.Label
        Anal_InputLabel_RMW             matlab.ui.control.Label
        Anal_InputLabel_alpha           matlab.ui.control.Label
        Anal_InputLabel_Gmax            matlab.ui.control.Label
        Anal_InputLabel_eta             matlab.ui.control.Label
        Anal_InputLabel_Bounds          matlab.ui.control.Label
        Anal_InputLabel                 matlab.ui.control.Label
        StartEstimationButton_Anal      matlab.ui.control.Button
        BestFitParametersTab            matlab.ui.container.Tab
        ExportTableButton_Anal          matlab.ui.control.Button
        VtransAnalEditField             matlab.ui.control.NumericEditField
        VtransmsEditFieldLabel          matlab.ui.control.Label
        ImportParametersAnalButton      matlab.ui.control.Button
        TransectNum_Anal                matlab.ui.control.NumericEditField
        TransectEditField_2Label        matlab.ui.control.Label
        UITable_Analytical              matlab.ui.control.Table
        TreeCropModuleTab               matlab.ui.container.Tab
        CriticalWindSpeedofTreefallButtonGroup  matlab.ui.container.ButtonGroup
        StdVcTreeLabel                  matlab.ui.control.Label
        MeanHDBHEditField               matlab.ui.control.NumericEditField
        MeanHDBH2m3Label                matlab.ui.control.Label
        StdHDBHEditField                matlab.ui.control.NumericEditField
        StdHDBH2m3EditFieldLabel        matlab.ui.control.Label
        MeanVcTreeLabel                 matlab.ui.control.Label
        FailureModeTreePropertyLabel    matlab.ui.control.Label
        StemBreakageKnot10Button        matlab.ui.control.ToggleButton
        StemBreakageKnot075Button       matlab.ui.control.ToggleButton
        UprootUnsaturatedButton         matlab.ui.control.ToggleButton
        UprootSaturatedButton           matlab.ui.control.ToggleButton
        UIAxes_VcTree                   matlab.ui.control.UIAxes
        CriticalWindSpeedofCornfallPanel  matlab.ui.container.Panel
        StdVcCornLabel                  matlab.ui.control.Label
        MeanVcCornLabel                 matlab.ui.control.Label
        CornGrowthStageSlider           matlab.ui.control.Slider
        CornGrowthStageLabel            matlab.ui.control.Label
        UIAxes_VcCorn                   matlab.ui.control.UIAxes
        WindFieldTab                    matlab.ui.container.Tab
        TornadoTrackPanel               matlab.ui.container.Panel
        WindFieldProductionPanel        matlab.ui.container.Panel
        WindFieldModelDropDown          matlab.ui.control.DropDown
        WindFieldModelDropDownLabel     matlab.ui.control.Label
        TranslationModelDropDown        matlab.ui.control.DropDown
        TranslationModelLabel           matlab.ui.control.Label
        InputNumTransectsLabel          matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        InputTorTransectsLabel          matlab.ui.control.Label
        InputTorTrackLabel              matlab.ui.control.Label
        TorTrackLON_EditField           matlab.ui.control.EditField
        LONGITUDELabel                  matlab.ui.control.Label
        TorTrackLAT_EditField           matlab.ui.control.EditField
        LATITUDELabel                   matlab.ui.control.Label
        SelectFileButton_TC             matlab.ui.control.Button
        CurrentFileEditField_TC         matlab.ui.control.EditField
        CurrentFileEditField_2Label     matlab.ui.control.Label
        ExcelSheetNumberEditField_TC    matlab.ui.control.NumericEditField
        ExcelSheetNumberEditFieldLabel  matlab.ui.control.Label
        GenerateWindFieldButton         matlab.ui.control.Button
        UITable_TorParameters           matlab.ui.control.Table
        ShowtornadotrackButton          matlab.ui.control.Button
        NumberofTransectsEditField      matlab.ui.control.NumericEditField
        NumberofTransectsLabel          matlab.ui.control.Label
        UITable_Transects               matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        TreefallAnalysisSoftwareLabel   matlab.ui.control.Label
    end

    properties (Access = private)
        % Populating variables that will be shared among functions
        filename_tc;    filepath_tc;
        filename_tf;    filepath_tf;
        
        X_trn;  Y_trn;  A_trn;
        X_m; Y_m; WD_m; XY_m;
        
        GS; DW; DL; Y_grid;
        
        y_c;    y_t;
        md_c;   md_t;
        
        tdata_c; tdata_t; tdata_a;

        k; k1; k2;
        y_grid_c;   y_grid_t;
        params_a;   funtyp_a;

        imp_indices_c;
        imp_indices_t;
        imp_indices_a;
        
        map;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [xlsx_file,path] = uigetfile({'*.xlsx';'*.xls'},'Select .xlsx file with tree-fall coordinates');
            if isequal(xlsx_file,0)
                app.CurrentFileEditField.Value = 'No file detected';
            else
                app.CurrentFileEditField.Value = xlsx_file;
            end
            app.filename_tf = xlsx_file;
            app.filepath_tf = path;       
        end

        % Value changed function: ObservedTreefallPatternTypeSwitch
        function ObservedTreefallPatternTypeSwitchValueChanged(app, event)
            TFtype = app.ObservedTreefallPatternTypeSwitch.Value;
            gs = app.GS;
            y_grid = app.Y_grid;
            
            if isempty(y_grid)
                msgbox('No tree-fall input data','Error','error');
                return                
            end
            
            if strcmp(TFtype,'Not-averaged')
                x = app.A_trn;
                y = app.Y_trn;
            
                cla(app.UIAxes5);
                plot(app.UIAxes5,x,y,'.','MarkerSize',8,'color','[0, 0.4470, 0.7410]')                
                legend(app.UIAxes5,'Tree-falls')
                xlim(app.UIAxes5,[0 400])

                cla(app.UIAxes_GC_Obs);
                quiver(app.UIAxes_GC_Obs,app.X_trn,app.Y_trn,cosd(app.A_trn)./sqrt(cosd(app.A_trn).^2+sind(app.A_trn).^2),sind(app.A_trn)./sqrt(cosd(app.A_trn).^2+sind(app.A_trn).^2),0.4,'linewidth',1.2,'color','[0, 0.4470, 0.7410]')
                legend(app.UIAxes_GC_Obs,'Observed') 
                ylim(app.UIAxes_GC_Obs,[min(app.Y_trn)-gs max(app.Y_trn)+gs])
                xlim(app.UIAxes_GC_Obs,[min(app.X_trn)-gs/2 max(app.X_trn)+gs/2])
                if app.DW/2 <= app.DL
                    pbaspect(app.UIAxes_GC_Obs,[2 1 1])
                elseif app.DL/2 <= app.DW
                    pbaspect(app.UIAxes_GC_Obs,[1 2 1])                        
                else
                    pbaspect(app.UIAxes_GC_Obs,[1 1 1])
                end
            else
                i_nan = isnan(app.WD_m);
                x = app.WD_m(~i_nan);
                y = y_grid(~i_nan);

                cla(app.UIAxes5);
                plot(app.UIAxes5,x,y,'.','MarkerSize',15,'color','[0, 0.4470, 0.7410]')
                legend(app.UIAxes5,'Averaged Tree-falls') 
                xlim(app.UIAxes5,[0 400])
            
                cla(app.UIAxes_GC_Obs);
                quiver(app.UIAxes_GC_Obs,zeros(size(y_grid,1),size(y_grid,2)),y_grid,app.X_m./app.XY_m,app.Y_m./app.XY_m,0.2,'linewidth',1.2,'color','[0, 0.4470, 0.7410]')
                legend(app.UIAxes_GC_Obs,'Observed')
                ylim(app.UIAxes_GC_Obs,[min(app.Y_trn)-gs max(app.Y_trn)+gs])
                xlim(app.UIAxes_GC_Obs,[-gs gs])
                pbaspect(app.UIAxes_GC_Obs,[1 4 1])
            end
            
        end

        % Button pushed function: InputCompleteButton
        function InputCompleteButtonPushed(app, event)
            if isempty(app.filename_tf)
                msgbox('No file detected');
                return
            else
                xlsx_file = app.filename_tf;
                path      = app.filepath_tf;
                sheetnum  = app.ExcelSheetNumber.Value;
                if floor(sheetnum) ~= ceil(sheetnum)
                    msgbox('Excel Sheet Number must be an integer', 'Error', 'error');
                    return
                elseif isequal(xlsx_file,0)
                    msgbox('No file detected', 'Error', 'error');
                    return                        
                elseif strcmp(xlsx_file,'No file detected')
                    msgbox('No file detected', 'Error', 'error');
                    return                        
                else
                    
                    % Define tornado center coordinates
                    LON_s_tc = app.StartLON_EditField_TC.Value;
                    LAT_s_tc = app.StartLAT_EditField_TC.Value;
                    LON_e_tc = app.EndLON_EditField_TC.Value;
                    LAT_e_tc = app.EndLAT_EditField_TC.Value;
                    
                    % Geographic coordinate to projected coordinate conversion
                    dczone = utmzone(LAT_s_tc,LON_s_tc);    %   Identify UTM zone
                    utmstruct = defaultm('utm');
                    utmstruct.zone = dczone;
                    utmstruct.geoid = wgs84Ellipsoid;
                    utmstruct = defaultm(utmstruct);

                    if isnan(LAT_s_tc) || isnan(LON_s_tc) || isnan(LAT_e_tc) || isnan(LON_e_tc)
                        msgbox('Tornado Path Input Invalid', 'Error', 'error')
                        return
                    else
                        [X_TC,Y_TC] = projfwd(utmstruct,[LAT_s_tc LAT_e_tc],[LON_s_tc LON_e_tc]);
%                         Trans_Angle = atand((Y_TC(2)-Y_TC(1))/(X_TC(2)-X_TC(1)));   % Tornado translation angle (Zero degree is EAST increasing CCW)

                        Xd_tc = X_TC(2)-X_TC(1); 
                        Yd_tc = Y_TC(2)-Y_TC(1);
                            
                        if Xd_tc >= 0 && Yd_tc >= 0
                            Trans_Angle = atand(abs(Yd_tc/Xd_tc));
                        elseif Xd_tc <= 0 && Yd_tc >= 0
                            Trans_Angle = 180-atand(abs(Yd_tc/Xd_tc));
                        elseif Xd_tc <= 0 && Yd_tc <= 0
                            Trans_Angle = 180+atand(abs(Yd_tc/Xd_tc));
                        elseif Xd_tc >= 0 && Yd_tc <= 0
                            Trans_Angle = 360-atand(abs(Yd_tc/Xd_tc));
                        end                           
                    end


                    % Define tree-fall coordinates
                    LON_s_tree_chr = app.StartLON_EditField_Tree.Value;
                    LAT_s_tree_chr = app.StartLAT_EditField_Tree.Value;
                    
                    try
                        LON_start = xlsread([path xlsx_file],sheetnum,char(LON_s_tree_chr));       % Tree-fall polyline starting point (longitude)
                        LAT_start = xlsread([path xlsx_file],sheetnum,char(LAT_s_tree_chr));       % Tree-fall polyline starting point (latitude)
                    catch ME
                        if strcmp(ME.identifier,'MATLAB:xlsread:WorksheetNotFound')
                            msgbox('Invalid Excel Worksheet Number', 'Error', 'error');
                            return
                        elseif strcmp(ME.identifier,'MATLAB:xlsread:RangeSelection')
                            msgbox('Tree-fall Coordinates Starting Point Input Invalid', 'Error', 'error');
                            return
                        end
                    end

                    DirType = app.TreefallDirectionSwitch.Value;
            
                    if strcmp(DirType,'Needs Calculation')
                        
                        LON_e_tree_chr = app.EndLON_EditField_Tree.Value;
                        LAT_e_tree_chr = app.EndLAT_EditField_Tree.Value;
                        
                        try
                            LON_end   = xlsread([path xlsx_file],sheetnum,char(LON_e_tree_chr));       % Tree-fall polyline ending point (longitude)
                            LAT_end   = xlsread([path xlsx_file],sheetnum,char(LAT_e_tree_chr));       % Tree-fall polyline ending point (latitude)
                        catch ME
                            if strcmp(ME.identifier,'MATLAB:xlsread:RangeSelection')
                                msgbox('Tree-fall Coordinates End Point Input Invalid', 'Error', 'error');
                                return
                            end
                        end
                                                
                        if isempty(LON_start) || isempty(LAT_start) || isempty(LON_end) || isempty(LAT_end)
                            msgbox('Tree-fall Coordinates Input Invalid', 'Error', 'error');
                            return
                        elseif size(LON_start,2) ~= 1 || size(LAT_start,2) ~= 1 || size(LON_end,2) ~= 1 || size(LAT_end,2) ~= 1
                            msgbox('Input must be in a form of an array, not maxtrix', 'Error', 'error');
                            return
                        elseif range([size(LON_start,1) size(LAT_start,1) size(LON_end,1) size(LAT_end,1)]) ~= 0
                            msgbox('Treefall coordinates length must be the same', 'Error', 'error');
                            return
                        end 
                        
                        [X_st,Y_st] = projfwd(utmstruct,LAT_start,LON_start);  % Projected Coordinate in m                    
                        [X_ed,Y_ed] = projfwd(utmstruct,LAT_end,LON_end);      % Projected Coordinate in m

                        if isempty(LAT_end) || isempty(LON_end)
                            msgbox(['Turn the Tree-fall Direction Switch to the other side';'or Check Tree-fall Coordinates Ending Input'], 'Error', 'error');
                            return
                        end
                        
                        %---- Transform Tree-fall Pattern ----%
                        % Calculating Tree-fall Direction
                        wd_geo = nan(size(X_st,1),size(X_st,2));                       
                        for i = 1:length(X_st)
    
                            Xd = X_ed(i)-X_st(i); 
                            Yd = Y_ed(i)-Y_st(i);
                
                            if Xd >= 0 && Yd >= 0
                                wd_geo(i) = atand(abs(Yd/Xd));
                            elseif Xd <= 0 && Yd >= 0
                                wd_geo(i) = 180-atand(abs(Yd/Xd));
                            elseif Xd <= 0 && Yd <= 0
                                wd_geo(i) = 180+atand(abs(Yd/Xd));
                            elseif Xd >= 0 && Yd <= 0
                                wd_geo(i) = 360-atand(abs(Yd/Xd));
                            end
                        end
                                                
                    else

                        Dir_e_tree_chr = app.TreeDir_EditField.Value;

                        try
                            Tree_dir = xlsread([path xlsx_file],sheetnum,char(Dir_e_tree_chr));       % Tree-fall polyline ending point (latitude)                        
                        catch ME
                            if strcmp(ME.identifier,'MATLAB:xlsread:RangeSelection')
                                msgbox('Tree-fall Direction Input Invalid', 'Error', 'error');
                                return
                            end
                        end
                        
                        if isempty(LON_start) || isempty(LAT_start) || isempty(Tree_dir)
                            msgbox('Tree-fall Coordinates Input Invalid', 'Error', 'error');
                            return
                        elseif size(LON_start,2) ~= 1 || size(LAT_start,2) ~= 1 || size(Tree_dir,2) ~= 1
                            msgbox('Input must be in a form of an array, not maxtrix', 'Error', 'error');
                            return
                        elseif range([size(LON_start,1) size(LAT_start,1) size(Tree_dir,1)]) ~= 0
                            msgbox('Treefall coordinates length must be the same', 'Error', 'error');
                            return
                        end 

                        [X_st,Y_st] = projfwd(utmstruct,LAT_start,LON_start);  % Projected Coordinate in m

                        if isequal(Tree_dir,0)
                            msgbox(['Turn the Tree-fall Direction Switch to the other side';'or Check Tree-fall Direction Input'], 'Error', 'error');
                            return
                        end


                        %---- Transform Tree-fall Pattern ----%
                        % Calculating Tree-fall Direction
                        wd_geo = nan(size(X_st,1),size(X_st,2));                        
                        for i = 1:length(Tree_dir)
    
                            Xd = sind(Tree_dir(i)); 
                            Yd = cosd(Tree_dir(i));
                
                            if Xd >= 0 && Yd >= 0
                                wd_geo(i) = atand(abs(Yd/Xd));
                            elseif Xd <= 0 && Yd >= 0
                                wd_geo(i) = 180-atand(abs(Yd/Xd));
                            elseif Xd <= 0 && Yd <= 0
                                wd_geo(i) = 180+atand(abs(Yd/Xd));
                            elseif Xd >= 0 && Yd <= 0
                                wd_geo(i) = 360-atand(abs(Yd/Xd));
                            end
                        end
                        
                    end


                    % Rotational Matrix: Rotates the transects about the tornado center
                    rot_M  = [cosd(Trans_Angle) -sind(Trans_Angle);   % Tornado translating direction 
                              sind(Trans_Angle)  cosd(Trans_Angle)];  % Tornado translating direction    
                    rot_XY = [X_st Y_st]*rot_M;  % Rotated XY Coordinates 
                    rot_TC = [X_TC' Y_TC']*rot_M;
                    a_trn  = wd_geo-(Trans_Angle);            
                    k_ang  = find(a_trn < 0);

                    a_trn(k_ang) = a_trn(k_ang)+360;
                    x_trn = rot_XY(:,1)-rot_TC(1,1);
                    y_trn = rot_XY(:,2)-rot_TC(1,2);
            
                    x_trn = x_trn-max(x_trn)/2;
                    
                    app.A_trn = a_trn;
                    app.X_trn = x_trn;
                    app.Y_trn = y_trn;

                    
                    %---- Average Tree-fall Directions ----%
                    dw = max(y_trn)-min(y_trn);
                    dl = max(x_trn)-min(x_trn);

                    if dw >= 10 && dw < 20
                        gs = 1.25;
                        y_grid = [-20:gs:12.5]';
                    elseif dw >= 20 && dw < 40
                        gs = 2.5;
                        y_grid = [-40:gs:25]';
                    elseif dw >= 40 && dw < 80
                        gs = 5;
                        y_grid = [-100:gs:50]';
                    elseif dw >= 80 && dw < 160
                        gs = 10;
                        y_grid = [-160:gs:100]';
                    elseif dw >= 160 && dw < 320
                        gs = 20;
                        y_grid = [-320:gs:200]';                        
                    elseif dw >= 320 && dw < 640
                        gs = 40;
                        y_grid = [-640:gs:400]';
                    elseif dw >= 640 && dw < 1280
                        gs = 80;                        
                        y_grid = [-1280:gs:800]';
                    elseif dw >= 1280 && dw < 2560
                        gs = 160;                        
                        y_grid = [-2560:gs:1600]';
                    elseif dw >= 2560 && dw < 6400
                        gs = 320;                        
                        y_grid = [-5120:gs:3200]';
                    elseif dw >= 6400
                        msgbox('Damage Width is too large', 'Error', 'error');
                        return
                    elseif dw < 10
                        msgbox('Damage Width is too small', 'Error', 'error');
                        return
                    end 
                                        
                    app.Y_grid = y_grid;
                    app.GS = gs;
                    app.DL = dl;

                    x_m = nan(size(y_grid,1),size(y_grid,2));                        
                    y_m = nan(size(y_grid,1),size(y_grid,2));                        
                    wd_m = nan(size(y_grid,1),size(y_grid,2));                        
                    for ii = 1:length(y_grid)
    
                        i_d = find(y_trn >= y_grid(ii)-gs/2);
                        i_u = find(y_trn <  y_grid(ii)+gs/2);
                        i_int = intersect(i_d,i_u);
    
                        x_m(ii) = mean(cosd(a_trn(i_int)));
                        y_m(ii) = mean(sind(a_trn(i_int)));
    
                        if isnan(x_m(ii))
                            wd_m(ii) = NaN;
                        else
                            if x_m(ii) >= 0 && y_m(ii) >= 0
                                wd_m(ii) = atand(abs(y_m(ii)/x_m(ii)));
                            elseif x_m(ii) <= 0 && y_m(ii) >= 0
                                wd_m(ii) = 180-atand(abs(y_m(ii)/x_m(ii)));
                            elseif x_m(ii) <= 0 && y_m(ii) <= 0
                                wd_m(ii) = 180+atand(abs(y_m(ii)/x_m(ii)));
                            elseif x_m(ii) >= 0 && y_m(ii) <= 0
                                wd_m(ii) = 360-atand(abs(y_m(ii)/x_m(ii)));
                            end
                        end
    
                    end

                    xy_m = sqrt(x_m.^2+y_m.^2);
            
                    app.X_m  = x_m;
                    app.Y_m  = y_m;                    
                    app.WD_m = wd_m;
                    app.XY_m = xy_m;
                    app.DW = max(y_grid(~isnan(x_m)))-min(y_grid(~isnan(x_m)));
                                        
                    quiver(app.UIAxes,x_trn,y_trn,cosd(a_trn)./sqrt(cosd(a_trn).^2+sind(a_trn).^2),sind(a_trn)./sqrt(cosd(a_trn).^2+sind(a_trn).^2),0.4,'linewidth',1.2,'color','[0, 0.4470, 0.7410]')
                    legend(app.UIAxes,'Observed') 
                    ylim(app.UIAxes,[min(y_trn)-gs max(y_trn)+gs])
                    xlim(app.UIAxes,[min(x_trn)-gs/2 max(x_trn)+gs/2])

                    if dw/dl > 1
                        pbaspect(app.UIAxes,[1 round(dw/dl,1) 1])
                    else
                        pbaspect(app.UIAxes,[round(dl/dw,1) 1 1])
                    end
                    
                    quiver(app.UIAxes2,zeros(size(y_grid,1),size(y_grid,2)),y_grid,x_m./xy_m,y_m./xy_m,0.2,'linewidth',1.5,'color','[0.8500, 0.3250, 0.0980]')
                    legend(app.UIAxes2,'Averaged') 
                    ylim(app.UIAxes2,[min(y_trn)-gs max(y_trn)+gs])
                    xlim(app.UIAxes2,[-gs gs])
                    pbaspect(app.UIAxes2,[1 4 1])
                end
            end            
        end

        % Button pushed function: StartEstimationButton_Catalog
        function StartEstimationButton_CatalogPushed(app, event)
            C_Vt_ub    = app.Catalog_Vtrans_ub.Value;
            C_gmax_ub  = app.Catalog_gmax_ub.Value;
            C_alpha_ub = app.Catalog_alpha_ub.Value;
            C_rmax_ub  = app.Catalog_rmax_ub.Value;
            C_phi_ub   = app.Catalog_phi_ub.Value;
            C_Vc_ub    = app.Catalog_Vc_ub.Value;
            
            C_Vt_lb    = app.Catalog_Vtrans_lb.Value;
            C_gmax_lb  = app.Catalog_gmax_lb.Value;
            C_alpha_lb = app.Catalog_alpha_lb.Value;   
            C_rmax_lb  = app.Catalog_rmax_lb.Value;
            C_phi_lb   = app.Catalog_phi_lb.Value;
            C_Vc_lb    = app.Catalog_Vc_lb.Value;
            
            Vt_gs = 2.5; gmax_gs = 0.25; alpha_gs = 5; phi_gs = 0.05; Vc_gs = 2.5;
            rmax1_gs = 2.5; rmax2_gs = 5; rmax3_gs = 10; rmax4_gs = 25; 
            if C_Vt_ub < C_Vt_lb || C_gmax_ub < C_gmax_lb || C_alpha_ub < C_alpha_lb || C_rmax_ub < C_rmax_lb || C_phi_ub < C_phi_lb || C_Vc_ub < C_Vc_lb
                msgbox('Invalid bounds','Error','error');
                return
            elseif C_Vt_ub > 30 || C_Vt_lb < 5 || C_gmax_ub > 6 || C_gmax_lb < 0.5 || C_alpha_ub > 70 || C_alpha_lb < 0 || C_rmax_ub > 350 || C_rmax_lb < 2.5 || ...
                   C_phi_ub > 1.0 || C_phi_lb < 0.5 || C_Vc_ub > 60 || C_Vc_lb < 10
                msgbox('Maximum/minimum bounds exceeded','Error','error');
                return
            elseif ~any(floor(C_Vt_ub/Vt_gs)-floor(C_Vt_lb/Vt_gs)) && any(rem(C_Vt_ub,Vt_gs)) && any(rem(C_Vt_lb,Vt_gs))
                msgbox('Vtrans bounds too small; increase Vtrans bounds','Error','error');
                return
            elseif ~any(floor(C_gmax_ub/gmax_gs)-floor(C_gmax_lb/gmax_gs)) && any(rem(C_gmax_ub,gmax_gs)) && any(rem(C_gmax_lb,gmax_gs))
                msgbox('Gmax bounds too small; increase Gmax bounds','Error','error');
                return
            elseif ~any(floor(C_alpha_ub/alpha_gs)-floor(C_alpha_lb/alpha_gs)) && any(rem(C_alpha_ub,alpha_gs)) && any(rem(C_alpha_lb,alpha_gs))
                msgbox('alpha bounds too small; increase alpha bounds','Error','error');
                return
            elseif ~any(floor(C_phi_ub/phi_gs)-floor(C_phi_lb/phi_gs)) && any(rem(C_phi_ub,phi_gs)) && any(rem(C_phi_lb,phi_gs))
                msgbox('phi bounds too small; increase phi bounds','Error','error');                
                return
            elseif ~any(floor(C_Vc_ub/Vc_gs)-floor(C_Vc_lb/Vc_gs)) && any(rem(C_Vc_ub,Vc_gs)) && any(rem(C_Vc_lb,Vc_gs))
                msgbox('Vcrit bounds too small; increase Vcrit bounds','Error','error');
                return                    
            elseif C_rmax_ub <= 20
                if ~any(floor(C_rmax_ub/rmax1_gs)-floor(C_rmax_lb/rmax1_gs)) && any(rem(C_rmax_ub,rmax1_gs)) && any(rem(C_rmax_lb,rmax1_gs))
                    msgbox('Rmax bounds too small; increase Rmax bounds','Error','error');
                    return
                end
            elseif C_rmax_ub <= 50 && C_rmax_ub > 20 
                if ~any(floor(C_rmax_ub/rmax2_gs)-floor(C_rmax_lb/rmax2_gs)) && any(rem(C_rmax_ub,rmax2_gs)) && any(rem(C_rmax_lb,rmax2_gs))
                    msgbox('Rmax bounds too small; increase Rmax bounds','Error','error');
                    return
                end
            elseif C_rmax_ub <= 100 && C_rmax_ub > 50
                if ~any(floor(C_rmax_ub/rmax3_gs)-floor(C_rmax_lb/rmax3_gs)) && any(rem(C_rmax_ub,rmax3_gs)) && any(rem(C_rmax_lb,rmax3_gs))
                    msgbox('Rmax bounds too small; increase Rmax bounds','Error','error');
                    return
                end
            elseif C_rmax_ub <= 350 && C_rmax_ub > 125
                if ~any(floor(C_rmax_ub/rmax4_gs)-floor(C_rmax_lb/rmax4_gs)) && any(rem(C_rmax_ub,rmax4_gs)) && any(rem(C_rmax_lb,rmax4_gs))
                    msgbox('Rmax bounds too small; increase Rmax bounds','Error','error');
                    return
                end
            end

            DW_anal = app.DW;
            Y_tree = app.Y_grid(~isnan(app.X_m));
            xall_tree = app.X_m(~isnan(app.X_m));
            yall_tree = app.Y_m(~isnan(app.X_m));            

            if isempty(DW_anal) || isempty(Y_tree)
                msgbox('No tree-fall input data','Error','error');
                return                
            end            
            
            h = msgbox('Please wait ...');
            
            
            %---- Tree-fall Catalog ----%
            % Tree-fall Parameters subjected to change as we increase tree-fall catalog database 
            Vtrans_all = 5:Vt_gs:30;
            Gmax_all   = 0.5:gmax_gs:6;
            alpha_all  = 0:alpha_gs:70;
            RMW_all    = [2.5:rmax1_gs:20 25:rmax2_gs:50 60:rmax3_gs:100 125:rmax4_gs:350];
            phi_all    = 0.5:phi_gs:1.0;
            Vcrit_all  = 10:Vc_gs:60;
            
            fact_design = fullfact([length(Vtrans_all) length(Gmax_all) length(alpha_all) length(RMW_all) length(phi_all) length(Vcrit_all)]);           

            if isempty(DW_anal)
                msgbox('No tree-fall input data','Error','error');
                return
            end
            
            if DW_anal >= 10 && DW_anal < 20
                load('Treefall_Catalog_database.mat', 'RV_DW_1','RV_MD_1','RV_i_1','RV_DW_2','RV_MD_2','RV_i_2');
                DW_all = [RV_DW_1; RV_DW_2];
                MD_all = [RV_MD_1; RV_MD_2];
                i_E = [RV_i_1; RV_i_2];
                gs  = 1.25;
                y = (-20:gs:12.5)';
            elseif DW_anal >= 20 && DW_anal < 40
                load('Treefall_Catalog_database.mat', 'RV_DW_2','RV_MD_2','RV_i_2','RV_DW_3','RV_MD_3','RV_i_3');
                DW_all = [RV_DW_2; RV_DW_3];
                MD_all = [RV_MD_2; RV_MD_3];
                i_E = [RV_i_2; RV_i_3];
                gs  = 2.5;
                y = (-40:gs:25)';
            elseif DW_anal >= 40 && DW_anal < 80
                load('Treefall_Catalog_database.mat', 'RV_DW_3','RV_MD_3','RV_i_3','RV_DW_4','RV_MD_4','RV_i_4');
                DW_all = [RV_DW_3; RV_DW_4];
                MD_all = [RV_MD_3; RV_MD_4];
                i_E = [RV_i_3; RV_i_4];
                gs  = 5;
                y = (-80:gs:50)';
            elseif DW_anal >= 80 && DW_anal < 160
                load('Treefall_Catalog_database.mat', 'RV_DW_4','RV_MD_4','RV_i_4','RV_DW_5','RV_MD_5','RV_i_5');
                DW_all = [RV_DW_4; RV_DW_5];
                MD_all = [RV_MD_4; RV_MD_5];
                i_E = [RV_i_4; RV_i_5];
                gs  = 10;
                y = (-160:gs:100)';
            elseif DW_anal >= 160 && DW_anal < 320
                load('Treefall_Catalog_database.mat', 'RV_DW_5','RV_MD_5','RV_i_5','RV_DW_6','RV_MD_6','RV_i_6');
                DW_all = [RV_DW_5; RV_DW_6];
                MD_all = [RV_MD_5; RV_MD_6];
                i_E = [RV_i_5; RV_i_6];
                gs  = 20;
                y = (-320:gs:200)';
            elseif DW_anal >= 320 && DW_anal < 640
                load('Treefall_Catalog_database.mat', 'RV_DW_6','RV_MD_6','RV_i_6','RV_DW_7','RV_MD_7','RV_i_7');
                DW_all = [RV_DW_6; RV_DW_7];
                MD_all = [RV_MD_6; RV_MD_7];
                i_E = [RV_i_6; RV_i_7];
                gs  = 40;
                y = (-640:gs:400)';
            elseif DW_anal >= 640 && DW_anal < 1280
                load('Treefall_Catalog_database.mat', 'RV_DW_7','RV_MD_7','RV_i_7','RV_DW_8','RV_MD_8','RV_i_8');
                DW_all = [RV_DW_7; RV_DW_8];
                MD_all = [RV_MD_7; RV_MD_8];
                i_E = [RV_i_7; RV_i_8];
                gs  = 80;
                y = (-1280:gs:800)';
            elseif DW_anal >= 1280 && DW_anal < 2560
                load('Treefall_Catalog_database.mat', 'RV_DW_8','RV_MD_8','RV_i_8','RV_DW_9','RV_MD_9','RV_i_9');
                DW_all = [RV_DW_8; RV_DW_9];
                MD_all = [RV_MD_8; RV_MD_9];
                i_E = [RV_i_8; RV_i_9];
                gs  = 160;
                y = (-2560:gs:1600)';
            elseif DW_anal >= 2560 && DW_anal < 6400
                load('Treefall_Catalog_database.mat', 'RV_DW_8','RV_MD_8','RV_i_8','RV_DW_9','RV_MD_9','RV_i_9');
                DW_all = [RV_DW_8; RV_DW_9];
                MD_all = [RV_MD_8; RV_MD_9];
                i_E = [RV_i_8; RV_i_9];
                gs  = 320;
                y = (-5120:gs:3200)';
            end

            i_ub = find(DW_all <= 1.2*DW_anal);
            i_lb = find(DW_all >= 0.8*DW_anal);
            i_dw = intersect(i_ub,i_lb);
            
            E = NaN(size(i_dw,1),1);
            for i = 1:length(i_dw)

                del_DW  = [];
                del_dir = [];
                del_dw1 = [];
                del_dw2 = [];

                C = []; ia = []; ib = []; y_p = [];

                if ~isnan(DW_all(i_dw(i)))
                    cosB = zeros(size(Y_tree,1),size(Y_tree,2));

                    y_p  = y(~isnan(MD_all(i_dw(i),:)));
                    md_p = MD_all(i_dw(i),~isnan(MD_all(i_dw(i),:)));
                    [C,ia,ib] = intersect(y_p,Y_tree);
        
                    if ~isempty(C)
                        for ii = 1:length(ia)
                            cosB(ib(ii)) = dot([cosd(md_p(ia(ii))),sind(md_p(ia(ii)))],[xall_tree(ib(ii)),yall_tree(ib(ii))]);
                        end
                    end
                    del_DW = ((DW_all(i_dw(i))-DW_anal)/(DW_anal))^2;
                    del_dir = (sum((1-cosB).^2))/size(Y_tree,1);
                    del_dw1 = ((max(Y_tree)-max(y_p))/max(Y_tree))^2;
                    del_dw2 = ((min(Y_tree)-min(y_p))/min(Y_tree))^2;
                    E(i) = sqrt(del_DW+del_dir+del_dw1+del_dw2);
                else
                    E(i) = NaN;
                end
                
            end

            [E_sort,E_sort_i] = sort(E,'ascend');
            Vt_table    = Vtrans_all(fact_design(i_E(i_dw(E_sort_i)),1))';
            Gmax_table  = Gmax_all(fact_design(i_E(i_dw(E_sort_i)),2))';
            alpha_table = alpha_all(fact_design(i_E(i_dw(E_sort_i)),3))';
            RMW_table   = RMW_all(fact_design(i_E(i_dw(E_sort_i)),4))';
            phi_table   = phi_all(fact_design(i_E(i_dw(E_sort_i)),5))';
            Vcrit_table = Vcrit_all(fact_design(i_E(i_dw(E_sort_i)),6))';
            
            int.vt_ub = find(Vt_table <= C_Vt_ub);
            int.vt_lb = find(Vt_table >= C_Vt_lb);
            int.gm_ub = find(Gmax_table <= C_gmax_ub);
            int.gm_lb = find(Gmax_table >= C_gmax_lb);
            int.al_ub = find(alpha_table <= C_alpha_ub);
            int.al_lb = find(alpha_table >= C_alpha_lb);
            int.rm_ub = find(RMW_table <= C_rmax_ub);
            int.rm_lb = find(RMW_table >= C_rmax_lb);
            int.ph_ub = find(phi_table <= C_phi_ub);
            int.ph_lb = find(phi_table >= C_phi_lb);
            int.vc_ub = find(Vcrit_table <= C_Vc_ub);
            int.vc_lb = find(Vcrit_table >= C_Vc_lb);
            
            Fnames = fieldnames(int);
            int_P = int.(Fnames{1});

            for ii = 2:length(Fnames)
                int_P = intersect(int_P,int.(Fnames{ii}));
            end
            
            no = (1:length(int_P))';
            Error = E_sort(int_P);
            Vt    = Vt_table(int_P);
            Gmax  = Gmax_table(int_P);
            alpha = alpha_table(int_P);
            RMW   = RMW_table(int_P);
            phi   = phi_table(int_P);
            Vcrit = Vcrit_table(int_P);            
            Vmax  = Vt.*(Gmax+1);
            
            app.tdata_c = table(no,Error,Vt,Gmax,alpha,RMW,phi,Vcrit,Vmax);
            if size(app.tdata_c,1) >= 1000
                app.UITable_Catalog.Data = app.tdata_c(1:1000,:);
            else
                app.UITable_Catalog.Data = app.tdata_c;
            end

            app.y_grid_c = y;
            app.y_c = ~isnan(MD_all(i_dw(E_sort_i(int_P)),:));
            app.md_c = MD_all(i_dw(E_sort_i(int_P)),:);
            app.k1 = 1;

            h1 = app.UIAxes6_1;
            h2 = app.UIAxes6_2;
            h3 = app.UIAxes6_3;
            h4 = app.UIAxes6_4;
            h5 = app.UIAxes6_5;
            
            h_all = [h1 h2 h3 h4 h5];
            
            if size(app.md_c,1) >= 6
                for j = 1:5
                    x_m = cosd(app.md_c(app.k1+(j-1),app.y_c(app.k1+(j-1),:))');
                    y_m = sind(app.md_c(app.k1+(j-1),app.y_c(app.k1+(j-1),:))');
                    xy_m = sqrt(x_m.^2+y_m.^2);
                    
                    cla(h_all(j));
                    hold(h_all(j),'on')
                    quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree./sqrt(xall_tree.^2+yall_tree.^2),yall_tree./sqrt(xall_tree.^2+yall_tree.^2),0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                    quiver(h_all(j),zeros(size(y(app.y_c(app.k1+(j-1),:)))),y(app.y_c(app.k1+(j-1),:)), ...
                                    x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                    legend(h_all(j),'Observed','Simulated')
                    xlim(h_all(j),[-gs gs])
                    ylim(h_all(j),[min(Y_tree)-gs*2 max(Y_tree)+gs*2])
                    title(h_all(j),['Best-match #' num2str(app.k1+(j-1))])
                end
            else
                
                k1_dummy = ceil(size(app.md_c,1)/5)*5-size(app.md_c,1);
                ind_dummy = [5-(k1_dummy-1):5];
                
                for j = 1:5
                    if any(j == ind_dummy)
                        cla(h_all(j));
                        legend(h_all(j),[])
                        title(h_all(j),'-')
                    else
                        x_m = cosd(app.md_c(app.k1+(j-1),app.y_c(app.k1+(j-1),:))');
                        y_m = sind(app.md_c(app.k1+(j-1),app.y_c(app.k1+(j-1),:))');
                        xy_m = sqrt(x_m.^2+y_m.^2);
                        
                        cla(h_all(j));
                        hold(h_all(j),'on')
                        quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree./sqrt(xall_tree.^2+yall_tree.^2),yall_tree./sqrt(xall_tree.^2+yall_tree.^2),0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                        quiver(h_all(j),zeros(size(y(app.y_c(app.k1+(j-1),:)))),y(app.y_c(app.k1+(j-1),:)), ...
                                        x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                        legend(h_all(j),'Observed','Simulated')
                        xlim(h_all(j),[-gs gs])
                        ylim(h_all(j),[min(Y_tree)-gs*2 max(Y_tree)+gs*2])                        
                        title(h_all(j),['Best-match #' num2str(app.k1+(j-1))])
                    end
                end
            end

            close(h)
        end

        % Button pushed function: Catalog_NextPatternsButton
        function Catalog_NextPatternsButtonPushed(app, event)
            Y_tree = app.Y_grid(~isnan(app.X_m));
            xall_tree = app.X_m(~isnan(app.X_m));
            yall_tree = app.Y_m(~isnan(app.X_m));            
            
            yy = app.y_grid_c;
            y_c_all = app.y_c;
            md_c_all = app.md_c;

            app.k1 = app.k1+5;

            if isempty(app.k1)
                msgbox('Must complete the estimation first','Error','error')
                return
            elseif app.k1 > size(app.md_c,1)
                msgbox('No more patterns','Error','error')
                app.k1 = app.k1-5;
                return
            end

            h1 = app.UIAxes6_1;
            h2 = app.UIAxes6_2;
            h3 = app.UIAxes6_3;
            h4 = app.UIAxes6_4;
            h5 = app.UIAxes6_5;
            
            h_all = [h1 h2 h3 h4 h5];
            
            if floor(app.k1/5) < floor(size(app.md_c,1)/5)
                for j = 1:5
                    x_m = cosd(md_c_all(app.k1+(j-1),y_c_all(app.k1+(j-1),:))');
                    y_m = sind(md_c_all(app.k1+(j-1),y_c_all(app.k1+(j-1),:))');
                    xy_m = sqrt(x_m.^2+y_m.^2);

                    cla(h_all(j));
                    hold(h_all(j),'on')
                    quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree./sqrt(xall_tree.^2+yall_tree.^2),yall_tree./sqrt(xall_tree.^2+yall_tree.^2),0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                    quiver(h_all(j),zeros(size(yy(y_c_all(app.k1+(j-1),:)))),yy(y_c_all(app.k1+(j-1),:)), ...
                        x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                    legend(h_all(j),'Observed','Simulated')
                    title(h_all(j),['Best-match #' num2str(app.k1+(j-1))])
                end
            elseif floor(app.k1/5) == floor(size(app.md_c,1)/5)
                
                k1_dummy = ceil(size(app.md_c,1)/5)*5-size(app.md_c,1);
                ind_dummy = [5-(k1_dummy-1):5];
                
                for j = 1:5
                    if any(j == ind_dummy)
                        cla(h_all(j));
                        legend(h_all(j),[])
                        title(h_all(j),'-')
                    else
                        x_m = cosd(app.md_t(app.k1+(j-1),app.y_t(app.k1+(j-1),:))');
                        y_m = sind(app.md_t(app.k1+(j-1),app.y_t(app.k1+(j-1),:))');
                        xy_m = sqrt(x_m.^2+y_m.^2);

                        cla(h_all(j));
                        hold(h_all(j),'on')
                        quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree./sqrt(xall_tree.^2+yall_tree.^2),yall_tree./sqrt(xall_tree.^2+yall_tree.^2),0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                        quiver(h_all(j),zeros(size(yy(y_c_all(app.k1+(j-1),:)))),yy(y_c_all(app.k1+(j-1),:)), ...
                            x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                        legend(h_all(j),'Observed','Simulated')
                        title(h_all(j),['Best-match #' num2str(app.k1+(j-1))])
                    end
                end
            end
        end

        % Button pushed function: Catalog_PreviousPatternsButton
        function Catalog_PreviousPatternsButtonPushed(app, event)
            Y_tree = app.Y_grid(~isnan(app.X_m));
            xall_tree = app.X_m(~isnan(app.X_m));
            yall_tree = app.Y_m(~isnan(app.X_m));            
            
            yy = app.y_grid_c;
            y_c_all = app.y_c;
            md_c_all = app.md_c;

            if isempty(app.k1)
                msgbox('Must complete the estimation first','Error','error')
                return
            elseif app.k1 <= 1
                msgbox('No more patterns','Error','error')
                return                
            end

            app.k1 = app.k1-5;
            
            h1 = app.UIAxes6_1;
            h2 = app.UIAxes6_2;
            h3 = app.UIAxes6_3;
            h4 = app.UIAxes6_4;
            h5 = app.UIAxes6_5;
            
            h_all = [h1 h2 h3 h4 h5];
            
            for j = 1:5
                x_m = cosd(md_c_all(app.k1+(j-1),y_c_all(app.k1+(j-1),:))');
                y_m = sind(md_c_all(app.k1+(j-1),y_c_all(app.k1+(j-1),:))');
                xy_m = sqrt(x_m.^2+y_m.^2);
                
                cla(h_all(j));
                hold(h_all(j),'on')
                quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree,yall_tree,0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                quiver(h_all(j),zeros(size(yy(y_c_all(app.k1+(j-1),:)))),yy(y_c_all(app.k1+(j-1),:)), ...
                                x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                legend(h_all(j),'Observed','Simulated')
                title(h_all(j),['Best-match #' num2str(app.k1+(j-1))])
            end
        end

        % Button pushed function: ExportTableButton_Catalog
        function ExportTableButton_CatalogPushed(app, event)
            cpy_folder = uigetdir([],'Choose a folder to export the table');
            
            if isequal(cpy_folder,0)
                return
            end                       
            
            writetable(app.tdata_c,[cpy_folder '\Treefall_Parameter_Estimation_CatalogMethod_' datestr(now,'mmmddHHMM')  '.xlsx'],"AutoFitWidth",false)
        end

        % Cell selection callback: UITable_Catalog
        function UITable_CatalogCellSelection(app, event)
            % Retrieve indices of desired parameters from the Catalog table (UITable_Catalog)
            indices = event.Indices;
            app.imp_indices_c = indices;
        end

        % Button pushed function: ImportParametersCatalogButton
        function ImportParametersCatalogButtonPushed(app, event)
            % Import desired parameters to parameter table (UITable_TorParameters)
            indices = app.imp_indices_c;
            tNum  = app.TransectNum_Catalog.Value;              
            if isempty(indices)
                msgbox('Must select a row before inputting the parameters','Error','error')
                return
            elseif size(indices,1) > 1
                if length(unique(indices(:,1))) > 1
                    msgbox('Cannot input more than one row','Error','error')
                    return
                end
            elseif indices(2) ~= 1
                msgbox('Please, select the number in the "No" column','Error','error')
                return
            elseif floor(tNum) ~= ceil(tNum)
                msgbox('Transect # must be an integer','Error','error')
                return
            elseif isempty(app.UITable_TorParameters.Data)
                msgbox('Must define the number of transects in the "Wind Field" tab','Error','error')
                return
            elseif tNum > size(app.UITable_TorParameters.Data,1) 
                msgbox('Transect # is greater than the number of transects in the "Wind Field" tab','Error','error')
                return
            end
            
            tdata = app.UITable_Catalog.Data(indices(1),:);
            tdata(1,1) = num2cell(tNum);
            Tnew = tdata(:,[1,3:7]);

            app.UITable_TorParameters.Data(tNum,:) = Tnew;
        end

        % Button pushed function: StartEstimationButton_Trans
        function StartEstimationButton_TransPushed(app, event)
            T_Vt_ub    = app.Trans_Vtrans_ub.Value;
            T_gmax_ub  = app.Trans_gmax_ub.Value;
            T_alpha_ub = app.Trans_alpha_ub.Value;
            T_rmax_ub  = app.Trans_rmax_ub.Value;
            T_phi_ub   = app.Trans_phi_ub.Value;
            T_Vc_ub    = app.Trans_Vc_ub.Value;
            
            T_Vt_lb    = app.Trans_Vtrans_lb.Value;
            T_gmax_lb  = app.Trans_gmax_lb.Value;
            T_alpha_lb = app.Trans_alpha_lb.Value;   
            T_rmax_lb  = app.Trans_rmax_lb.Value;
            T_phi_lb   = app.Trans_phi_lb.Value;
            T_Vc_lb    = app.Trans_Vc_lb.Value;
            
            T_Vt_gs    = app.Trans_Vtrans_gs.Value;
            T_gmax_gs  = app.Trans_gmax_gs.Value;
            T_alpha_gs = app.Trans_alpha_gs.Value;
            T_rmax_gs  = app.Trans_rmax_gs.Value;
            T_phi_gs   = app.Trans_phi_gs.Value;
            T_Vc_gs    = app.Trans_Vc_gs.Value;

            if T_Vt_ub < T_Vt_lb || T_gmax_ub < T_gmax_lb || T_alpha_ub < T_alpha_lb || T_rmax_ub < T_rmax_lb || T_phi_ub < T_phi_lb || T_Vc_ub < T_Vc_lb
                msgbox('Invalid bounds','Error','error');
                return
            elseif T_Vt_ub <= 0 || T_gmax_ub <= 0 || T_rmax_ub <= 0 || T_phi_ub <= 0 || T_Vc_ub <= 0 || ...
                   T_Vt_lb <= 0 || T_gmax_lb <= 0 || T_rmax_lb <= 0 || T_phi_lb <= 0 || T_Vc_lb <= 0
                msgbox('Parameter cannot be zero or a negative number','Error','error');
                return
            elseif T_Vt_gs > T_Vt_ub || T_gmax_gs > T_gmax_ub || T_alpha_gs > T_alpha_ub || T_rmax_gs > T_rmax_ub || T_phi_gs > T_phi_ub || T_Vc_gs > T_Vc_ub
                msgbox('Parameter spacing too large','Error','error');
                return
            end
            
            if T_Vt_gs <= 0 
                if T_Vt_ub == T_Vt_lb
                    T_Vt_gs = 1;
                else
                    msgbox('Parameter Vtrans spacing cannot be zero or a negative number','Error','error');                
                    return
                end
            end
            
            if T_gmax_gs <= 0
                if T_gmax_ub == T_gmax_lb
                    T_gmax_gs = 1;
                else
                    msgbox('Parameter Gmax spacing cannot be zero or a negative number','Error','error');                
                    return
                end
            end
            
            if T_alpha_gs <= 0
                if T_alpha_ub == T_alpha_lb
                    T_alpha_gs = 1;
                else
                    msgbox('Parameter alpha spacing cannot be zero or a negative number','Error','error');                
                    return
                end
            end
            
            if T_rmax_gs <= 0
                if T_rmax_ub == T_rmax_lb
                    T_rmax_gs = 1;
                else
                    msgbox('Parameter RMW spacing cannot be zero or a negative number','Error','error');                
                    return
                end
            end 
            
            if T_phi_gs <= 0
                if T_phi_ub == T_phi_lb
                    T_phi_gs = 1;
                else
                    msgbox('Parameter phi spacing cannot be zero or a negative number','Error','error');                
                    return
                end
            end
            
            if T_Vc_gs <= 0
                if T_Vc_ub == T_Vc_lb
                    T_Vc_gs = 1;
                else
                    msgbox('Parameter Vcrit spacing cannot be zero or a negative number','Error','error');                
                    return
                end
            end
        
            if T_alpha_ub < 0 || T_alpha_lb < 0
                msgbox('Warning: alpha is negative. Tornado is anti-cyclonic');
            end
            
            DW_anal = app.DW;
            Y_tree = app.Y_grid(~isnan(app.X_m));
            xall_tree = app.X_m(~isnan(app.X_m));
            yall_tree = app.Y_m(~isnan(app.X_m));
            
            if isempty(DW_anal) || isempty(Y_tree)
                msgbox('No tree-fall input data','Error','error');
                return                
            end


            %---- Tree-fall Transect Method ----% 
            Vtrans_all = T_Vt_lb:T_Vt_gs:T_Vt_ub;
            Gmax_all   = T_gmax_lb:T_gmax_gs:T_gmax_ub;
            alpha_all  = T_alpha_lb:T_alpha_gs:T_alpha_ub;
            RMW_all    = T_rmax_lb:T_rmax_gs:T_rmax_ub;
            phi_all    = T_phi_lb:T_phi_gs:T_phi_ub;
            Vcrit_all  = T_Vc_lb:T_Vc_gs:T_Vc_ub;

            fact_size = length(Vtrans_all)*length(Gmax_all)*length(alpha_all)*length(RMW_all)*length(phi_all)*length(Vcrit_all);
            if fact_size > 1e7
                msgbox('Iteration size too large; reduce parameter bounds or increase gs','Error','error');
                return
            end
            
            fact_design = fullfact([length(Vtrans_all) length(Gmax_all) length(alpha_all) length(RMW_all) length(phi_all) length(Vcrit_all)]);
            
            if DW_anal >= 10 && DW_anal < 20
                gs  = 1.25;
                y = [-20:gs:12.5]';
                tor_end = 20;
            elseif DW_anal >= 20 && DW_anal < 40
                gs  = 2.5;
                y = [-40:gs:25]';
                tor_end = 40;
            elseif DW_anal >= 40 && DW_anal < 80
                gs  = 5;
                y = [-80:gs:50]';
                tor_end = 80;
            elseif DW_anal >= 80 && DW_anal < 160
                gs  = 10;
                y = [-160:gs:100]';
                tor_end = 160;
            elseif DW_anal >= 160 && DW_anal < 320
                gs  = 20;
                y = [-320:gs:200]';
                tor_end = 320;
            elseif DW_anal >= 320 && DW_anal < 640
                gs  = 40;
                y = [-640:gs:400]';
                tor_end = 640;
            elseif DW_anal >= 640 && DW_anal < 1280
                gs  = 80;
                y = [-1280:gs:800]';
                tor_end = 1280;
            elseif DW_anal >= 1280 && DW_anal < 2560
                gs  = 160;
                y = [-2560:gs:1600]';
                tor_end = 2560;
            elseif DW_anal >= 2560 && DW_anal < 6400
                gs  = 320;
                y = [-5120:gs:3200]';
                tor_end = 5120;
            end            
            
            tor_inc = gs/5;
            X = -tor_end:tor_inc:tor_end/2;
            Y = zeros(length(X),1);
            x = 0;

            k_topoX = ones(size(y));        %   Topographic factors in the x-direction
            k_topoY = ones(size(y));        %   Topographic factors in the y-direction
            
            %% Interaion Process Begins
            % Populate NaN arrays for iteration
            DW_all = NaN(size(fact_design,1),1);
            MD_all = NaN(size(fact_design,1),length(y));
            X_fail_all = NaN(size(fact_design,1),length(y));
            Y_fail_all = NaN(size(fact_design,1),length(y));

            VT_me    = NaN(size(fact_design,1),1);
            Gmax_me  = NaN(size(fact_design,1),1);
            alpha_me = NaN(size(fact_design,1),1);
            RMW_me   = NaN(size(fact_design,1),1);
            phi_me   = NaN(size(fact_design,1),1);
            Vcr_me   = NaN(size(fact_design,1),1);
            E = NaN(size(fact_design,1),1);

            if size(fact_design,1) > 100
                fig = uifigure;
                d = uiprogressdlg(fig,'Title','Please wait ...','Message','Optimization in progress: 0% done','Cancelable','on');
                drawnow
            else
                h = msgbox('Please wait ...');                                
            end
            
            t_start = tic;
            for jj = 1:size(fact_design,1)

                Ux = NaN(length(y),1);
                Uy = NaN(length(y),1);
                wd = NaN(length(y),1);    
                R  = zeros(length(y),1);
                t  = zeros(length(y),1);
                V  = zeros(length(y),1);
                A  = zeros(length(y),1);

                X_fail = NaN(1,length(y));

                Vx = zeros(length(y),1);
                Vy = zeros(length(y),1);
                Vrad = zeros(length(y),1);
                Vtan = zeros(length(y),1);
                Vtotal = zeros(length(y),1);
    
                for kk = 1:length(X)
                    for j = 1:length(y)
                        
                        R(j) = sqrt((x-X(kk))^2+(y(j)-Y(kk))^2);
                        if y(j)-Y(kk) == 0 && x-X(kk) == 0
                            t(j) = 0;  
                        else
                            t(j) = abs((y(j)-Y(kk))/(x-X(kk)));  
                        end

                        VT_x = Vtrans_all(fact_design(jj,1));
                        Vmx  = VT_x*Gmax_all(fact_design(jj,2));
                        
                        switch app.TranslationModelButtonGroup.SelectedObject.Text
                            case 'ConstantTranslation'
                                VT_x(j) = Vt_x;
                            case 'DecayingTranslation'
                                if R(j) <= RMW_all(fact_design(jj,4))  
                                    VT_x(j) = Vt_x;
                                else
                                    VT_x(j) = Vt_x*(RMW_all(fact_design(jj,4))/R(j))^phi_all(fact_design(jj,5));
                                end
                        end

                        if app.Rankine.Value == 1
                            if R(j) <= RMW_all(fact_design(jj,4)) 
                                V(j) = Vmx*(R(j)/RMW_all(fact_design(jj,4)))^phi_all(fact_design(jj,5));
                            else
                                V(j) = Vmx*(RMW_all(fact_design(jj,4))/R(j))^phi_all(fact_design(jj,5));    
                            end
                        elseif app.BurgersRott.Value == 1
                            V(j) = 1.4*(Vmx*(R(j)/RMW_all(fact_design(jj,4)))^-1)*(1-exp(-1.2564*(R(j)/RMW_all(fact_design(jj,4)))^2));
                        elseif app.Sullivan.Value == 1
                            V(j) = Vmx*((R(j)/RMW_all(fact_design(jj,4)))^2.4)*(0.3+0.7*(R(j)/RMW_all(fact_design(jj,4)))^7.89)^-0.435;
                        elseif app.ModifiedSullivan.Value == 1                            
                            V(j) = Vmx*((R(j)/RMW_all(fact_design(jj,4)))^10)*(0.091+0.909*(R(j)/RMW_all(fact_design(jj,4)))^22)^-0.5;
                        end

                        Vrad(j) = V(j)*cosd(alpha_all(fact_design(jj,3)));
                        Vtan(j) = V(j)*sind(alpha_all(fact_design(jj,3)));

                        if x >= X(kk) && y(j) >= Y(kk)
                            A(j) = atand(t(j));
                        elseif x < X(kk) && y(j) >= Y(kk)
                           A(j) = 180 - atand(t(j));
                        elseif x < X(kk) && y(j) < Y(kk)
                            A(j) = 180 + atand(t(j));
                        elseif x >= X(kk) && y(j) < Y(kk)
                            A(j) = 360 - atand(t(j));
                        end

                        Vx(j) = (-Vrad(j)*cosd(A(j)) - Vtan(j)*sind(A(j)) + VT_x)*k_topoX(j);
                        Vy(j) = (-Vrad(j)*sind(A(j)) + Vtan(j)*cosd(A(j)))*k_topoY(j);
                        Vtotal(j) = sqrt(Vx(j)^2+Vy(j)^2);

                        if Vtotal(j) >= Vcrit_all(fact_design(jj,6)) && isnan(Ux(j)) && isnan(Uy(j))
                            Ux(j) = Vx(j)/Vtotal(j);
                            Uy(j) = Vy(j)/Vtotal(j);
                            X_fail(j) = X(kk);
                        end

                        if Ux(j) >= 0 && Uy(j) >= 0
                            wd(j) = atand(abs(Uy(j))/abs(Ux(j)));
                        elseif Ux(j) >= 0 && Uy(j) <= 0
                            wd(j) = 360 - atand(abs(Uy(j))/abs(Ux(j)));
                        elseif Ux(j) <= 0 && Uy(j) <= 0
                            wd(j) = 180 + atand(abs(Uy(j))/abs(Ux(j)));
                        elseif Ux(j) <= 0 && Uy(j) >= 0
                            wd(j) = 180 - atand(abs(Uy(j))/abs(Ux(j)));
                        end
                        
                    end
                end
    
                X_fail_all(jj,:) = X_fail;
                k_wd       = ~isnan(wd);
                WD_fail    = wd(k_wd);
                Y_fail     = y(k_wd);
    
                if isempty(Y_fail)
                    DW_all(jj) = NaN;
                else
                    DW_all(jj) = Y_fail(end)-Y_fail(1);
                    MD_all(jj,k_wd) = WD_fail;
                    Y_fail_all(jj,k_wd) = Y_fail;
                end  

                VT_me(jj) = Vtrans_all(fact_design(jj,1));
                Gmax_me(jj) = Gmax_all(fact_design(jj,2));
                alpha_me(jj) = alpha_all(fact_design(jj,3));
                RMW_me(jj) = RMW_all(fact_design(jj,4));
                phi_me(jj) = phi_all(fact_design(jj,5));
                Vcr_me(jj) = Vcrit_all(fact_design(jj,6));

                
                %% Output comparison
                del_DW  = [];
                del_dir = [];
                del_dw1 = [];
                del_dw2 = [];

                C = []; ia = []; ib = []; y_p = [];
                
                if ~isnan(DW_all(jj))                    
                    cosB = zeros(size(Y_tree,1),size(Y_tree,2));
                    y_p  = y(~isnan(MD_all(jj,:)));
                    md_p = MD_all(jj,~isnan(MD_all(jj,:)));
                    [C,ia,ib] = intersect(y_p,Y_tree);
        
                    if ~isempty(C)
                        for ii = 1:length(ia)
                            cosB(ib(ii)) = dot([cosd(md_p(ia(ii))),sind(md_p(ia(ii)))],[xall_tree(ib(ii)),yall_tree(ib(ii))]);
                        end
                    end
                    del_DW = ((DW_all(jj)-DW_anal)/(DW_anal))^2;
                    del_dir = (sum((1-cosB).^2))/size(Y_tree,1);
                    del_dw1 = ((max(Y_tree)-max(y_p))/max(Y_tree))^2;
                    del_dw2 = ((min(Y_tree)-min(y_p))/min(Y_tree))^2;
                    E(jj) = sqrt(del_DW+del_dir+del_dw1+del_dw2);
                else
                    E(jj) = NaN;
                end
                
                
                %% Message box / Progress bar
                if size(fact_design,1) > 100
                    if jj == round(size(fact_design,1)/100)
                        t_end = toc(t_start);
                        t_est = 100*(t_end);
                    end

                    if mod(jj,round(size(fact_design,1)/100)) == 0
                        d.Value = jj/size(fact_design,1);
                        d.Message = ['Optimization in progress: ' num2str(round(jj/size(fact_design,1)*100,1)) ' % done; Estimated time: ' num2str(round(t_est/60)) ' min'];
                        drawnow
                    end
                
                    if d.CancelRequested
                        break
                    end
                end

            end

            if size(fact_design,1) > 100
                d.Message = 'Optimization complete';
                drawnow
                close(fig)
            else
                close(h)                
            end

            [E_sort,E_sort_i] = sort(E,'ascend');
            Vmax_me = VT_me(E_sort_i).*(Gmax_me(E_sort_i)+1);
            E_FP = [E_sort VT_me(E_sort_i) Gmax_me(E_sort_i) alpha_me(E_sort_i) RMW_me(E_sort_i) phi_me(E_sort_i) Vcr_me(E_sort_i) Vmax_me];
            
            no = (1:size(E_FP,1))';           
            Error = E_FP(:,1);        
            Vt    = E_FP(:,2);    
            Gmax  = E_FP(:,3);
            alpha = E_FP(:,4);            
            RMW   = E_FP(:,5);        
            phi   = E_FP(:,6);    
            Vcrit = E_FP(:,7);
            Vmax  = E_FP(:,8);

            switch app.TranslationModelButtonGroup.SelectedObject.Text
                case 'ConstantTranslation'
                    if app.BurgersRott.Value == 1 || app.Sullivan.Value == 1 || app.ModifiedSullivan.Value == 1                
                        phi = NaN(size(E_FP,1),1);
                    end
            end
    
            app.tdata_t = table(no,Error,Vt,Gmax,alpha,RMW,phi,Vcrit,Vmax);
            if size(app.tdata_t,1) >= 1000
                app.UITable_Trans.Data = app.tdata_t(1:1000,:);
            else
                app.UITable_Trans.Data = app.tdata_t;
            end

            app.y_grid_t = y;
            app.y_t = ~isnan(MD_all(E_sort_i,:));
            app.md_t = MD_all(E_sort_i,:);
            app.k2 = 1;
            
            h1 = app.UIAxes7_1;
            h2 = app.UIAxes7_2;
            h3 = app.UIAxes7_3;
            h4 = app.UIAxes7_4;
            h5 = app.UIAxes7_5;
            
            h_all = [h1 h2 h3 h4 h5];
            
            if size(app.md_t,1) >= 6
                for j = 1:5
                    x_m = cosd(app.md_t(app.k2+(j-1),app.y_t(app.k2+(j-1),:))');
                    y_m = sind(app.md_t(app.k2+(j-1),app.y_t(app.k2+(j-1),:))');
                    xy_m = sqrt(x_m.^2+y_m.^2);

                    cla(h_all(j));
                    hold(h_all(j),'on')
                    quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree./sqrt(xall_tree.^2+yall_tree.^2),yall_tree./sqrt(xall_tree.^2+yall_tree.^2),0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                    quiver(h_all(j),zeros(size(y(app.y_t(app.k2+(j-1),:)))),y(app.y_t(app.k2+(j-1),:)), ...
                                    x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                    legend(h_all(j),'Observed','Simulated')
                    xlim(h_all(j),[-gs gs])
                    ylim(h_all(j),[min(Y_tree)-gs*2 max(Y_tree)+gs*2])
                    title(h_all(j),['Best-match #' num2str(app.k2+(j-1))])
                end
            else
                k2_dummy = ceil(size(app.md_t,1)/5)*5-size(app.md_t,1);
                ind_dummy = [5-(k2_dummy-1):5];
                
                for j = 1:5
                    if any(j == ind_dummy)
                        cla(h_all(j));
                        legend(h_all(j),[])
                        title(h_all(j),'-')
                    else
                        x_m = cosd(app.md_t(app.k2+(j-1),app.y_t(app.k2+(j-1),:))');
                        y_m = sind(app.md_t(app.k2+(j-1),app.y_t(app.k2+(j-1),:))');
                        xy_m = sqrt(x_m.^2+y_m.^2);
                        
                        cla(h_all(j));
                        hold(h_all(j),'on')
                        quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree./sqrt(xall_tree.^2+yall_tree.^2),yall_tree./sqrt(xall_tree.^2+yall_tree.^2),0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                        quiver(h_all(j),zeros(size(y(app.y_t(app.k2+(j-1),:)))),y(app.y_t(app.k2+(j-1),:)), ...
                                        x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                        legend(h_all(j),'Observed','Simulated')
                        xlim(h_all(j),[-gs gs])
                        ylim(h_all(j),[min(Y_tree)-gs*2 max(Y_tree)+gs*2])                        
                        title(h_all(j),['Best-match #' num2str(app.k2+(j-1))])
                    end
                end
            end
        end

        % Button pushed function: Trans_NextPatternsButton
        function Trans_NextPatternsButtonPushed(app, event)
            Y_tree = app.Y_grid(~isnan(app.X_m));
            xall_tree = app.X_m(~isnan(app.X_m));
            yall_tree = app.Y_m(~isnan(app.X_m));            
            
            yy = app.y_grid_t;
            y_t_all = app.y_t;
            md_t_all = app.md_t;

            app.k2 = app.k2+5;

            if isempty(app.k2)
                msgbox('Must complete the estimation first','Error','error')
                return
            elseif app.k2 > size(app.md_t,1)
                msgbox('No more patterns','Error','error')
                app.k2 = app.k2-5;
                return
            end
                        
            h1 = app.UIAxes7_1;
            h2 = app.UIAxes7_2;
            h3 = app.UIAxes7_3;
            h4 = app.UIAxes7_4;
            h5 = app.UIAxes7_5;
            
            h_all = [h1 h2 h3 h4 h5];
            
            if floor(app.k2/5) < floor(size(app.md_t,1)/5)
                for j = 1:5
                    cla(h_all(j));
                    hold(h_all(j),'on')
                    quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree,yall_tree,0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                    quiver(h_all(j),zeros(size(yy(y_t_all(app.k2+(j-1),:)))),yy(y_t_all(app.k2+(j-1),:)), ...
                                    cosd(md_t_all(app.k2+(j-1),y_t_all(app.k2+(j-1),:))'),sind(md_t_all(app.k2+(j-1),y_t_all(app.k2+(j-1),:))'), ...
                                    0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                    legend(h_all(j),'Observed','Simulated')
                    title(h_all(j),['Best-match #' num2str(app.k2+(j-1))])
                end
            elseif floor(app.k2/5) == floor(size(app.md_t,1)/5)
                
                k2_dummy = ceil(size(app.md_t,1)/5)*5-size(app.md_t,1);
                ind_dummy = [5-(k2_dummy-1):5];
                
                for j = 1:5
                    if any(j == ind_dummy)
                        cla(h_all(j));
                        legend(h_all(j),[])
                        title(h_all(j),'-')
                    else
                        cla(h_all(j));
                        hold(h_all(j),'on')
                        quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree,yall_tree,0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                        quiver(h_all(j),zeros(size(yy(y_t_all(app.k2+(j-1),:)))),yy(y_t_all(app.k2+(j-1),:)), ...
                                        cosd(md_t_all(app.k2+(j-1),y_t_all(app.k2+(j-1),:))'),sind(md_t_all(app.k2+(j-1),y_t_all(app.k2+(j-1),:))'), ...
                                        0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                        legend(h_all(j),'Observed','Simulated')
                        title(h_all(j),['Best-match #' num2str(app.k2+(j-1))])
                    end
                end
            end
        end

        % Button pushed function: Trans_PreviousPatternsButton
        function Trans_PreviousPatternsButtonPushed(app, event)
            Y_tree = app.Y_grid(~isnan(app.X_m));
            xall_tree = app.X_m(~isnan(app.X_m));
            yall_tree = app.Y_m(~isnan(app.X_m));            
            
            yy = app.y_grid_t;
            y_t_all = app.y_t;
            md_t_all = app.md_t;

            if isempty(app.k2)
                msgbox('Must complete the estimation first','Error','error')
                return
            elseif app.k2 <= 1
                msgbox('No more patterns','Error','error')
                return
            end

            app.k2 = app.k2-5;
            
            h1 = app.UIAxes7_1;
            h2 = app.UIAxes7_2;
            h3 = app.UIAxes7_3;
            h4 = app.UIAxes7_4;
            h5 = app.UIAxes7_5;
            
            h_all = [h1 h2 h3 h4 h5];
            
            for j = 1:5
                x_m = cosd(md_t_all(app.k2+(j-1),y_t_all(app.k2+(j-1),:))');
                y_m = sind(md_t_all(app.k2+(j-1),y_t_all(app.k2+(j-1),:))');
                xy_m = sqrt(x_m.^2+y_m.^2);
                
                cla(h_all(j));
                hold(h_all(j),'on')
                quiver(h_all(j),zeros(size(Y_tree)),Y_tree,xall_tree,yall_tree,0.2,'linewidth',2,'color','[0, 0.4470, 0.7410]')
                quiver(h_all(j),zeros(size(yy(y_t_all(app.k2+(j-1),:)))),yy(y_t_all(app.k2+(j-1),:)), ...
                    x_m./xy_m,y_m./xy_m,0.2,'linewidth',2,'color','[0.8500, 0.3250, 0.0980]')
                legend(h_all(j),'Observed','Simulated')
                title(h_all(j),['Best-match #' num2str(app.k2+(j-1))])
            end
        end

        % Button pushed function: ExportTableButton_Trans
        function ExportTableButton_TransPushed(app, event)
            cpy_folder = uigetdir([],'Choose a folder to export the table');
            
            if isequal(cpy_folder,0)
                return
            end                       
            
            writetable(app.tdata_t,[cpy_folder '\Treefall_Parameter_Estimation_TransectMethod_' datestr(now,'mmmddHHMM')  '.xlsx'],"AutoFitWidth",false)
        end

        % Cell selection callback: UITable_Trans
        function UITable_TransCellSelection(app, event)
            % Retrieve indices of desired parameters from the Transect table (UITable_Trans)
            indices = event.Indices;
            app.imp_indices_t = indices;
        end

        % Button pushed function: ImportParametersTransButton
        function ImportParametersTransButtonPushed(app, event)
            % Import desired parameters to parameter table (UITable_TorParameters)
            indices = app.imp_indices_t;
            tNum    = app.TransectNum_Trans.Value;
            if isempty(indices)
                msgbox('Must select a row before inputting the parameters','Error','error')
                return
            elseif size(indices,1) > 1
                if length(unique(indices(:,1))) > 1
                    msgbox('Cannot input more than one row','Error','error')
                    return
                end
            elseif indices(2) ~= 1
                msgbox('Please, select the number in the "No" column','Error','error')
                return
            elseif floor(tNum) ~= ceil(tNum)
                msgbox('Transect # must be an integar','Error','error')
                return
            elseif isempty(app.UITable_TorParameters.Data)
                msgbox('Must define the number of transects in the "Wind Field" tab','Error','error')
                return
            elseif tNum > size(app.UITable_TorParameters.Data,1) 
                msgbox('Transect # is greater than the number of transects in the "Wind Field" tab','Error','error')
                return
            end

            tdata = app.UITable_Trans.Data(indices(1),:);
            tdata(1,1) = num2cell(tNum);
            Tnew = tdata(:,[1,3:7]);

            app.UITable_TorParameters.Data(tNum,:) = Tnew;            
        end

        % Button pushed function: StartEstimationButton_Anal
        function StartEstimationButton_AnalPushed(app, event)
            TFtype = app.ObservedTreefallPatternTypeSwitch.Value;
            y_grid = app.Y_grid;
            
            if isempty(y_grid)
                msgbox('No tree-fall input data','Error','error');
                return                
            end

            A_eta_ub   = app.Anal_eta_ub.Value;
            A_phi_ub   = app.Anal_phi_ub.Value;
            A_gmax_ub  = app.Anal_gmax_ub.Value;
            A_rmax_ub  = app.Anal_rmax_ub.Value;
            A_alpha_ub = app.Anal_alpha_ub.Value;
            
            A_eta_lb   = app.Anal_eta_lb.Value;
            A_phi_lb   = app.Anal_phi_lb.Value;
            A_gmax_lb  = app.Anal_gmax_lb.Value;
            A_rmax_lb  = app.Anal_rmax_lb.Value;
            A_alpha_lb = app.Anal_alpha_lb.Value;            
            
            if A_eta_ub < A_eta_lb || A_phi_ub < A_phi_lb || A_gmax_ub < A_gmax_lb || A_rmax_ub < A_rmax_lb || A_alpha_ub < A_alpha_lb
                msgbox('Invalid bounds', 'Error', 'error');
                return
            end

            %---- Analytical Method ----%
            % Geometrical Tree-fall relationship in L-Beta space
            numIter = app.NumIteration.Value;
            if numIter < 10000
                msgbox('Minimum of 10,000 iterations', 'Error', 'error');
                return
            end
            params_all = nan(numIter,5);
            fun_type = nan(numIter,1);
            fval_all = nan(numIter,1);
            if strcmp(TFtype,'Not-averaged')
                x1 = app.A_trn;
                y1 = app.Y_trn;
                Msize = 10;                                                 % MarkerSize for UIAxes5
            else
                i_nan = isnan(app.WD_m);
                x1 = app.WD_m(~i_nan);
                y1 = y_grid(~i_nan);
                Msize = 15;                                                 % MarkerSize for UIAxes5
            end

            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please wait ...','Message','Optimization in progress: 0% done','Cancelable','on');
            drawnow

            t_start = tic;
            dummy_x = 1:1:359;
            for iter = 1:numIter
                n  = round(A_eta_lb+(A_eta_ub-A_eta_lb)*rand,2);
                a  = round(A_alpha_lb+(A_alpha_ub-A_alpha_lb)*rand,2);
                p  = round(A_phi_lb+(A_phi_ub-A_phi_lb)*rand,2);
                G  = round(A_gmax_lb+(A_gmax_ub-A_gmax_lb)*rand,2);
                R  = round(A_rmax_lb+(A_rmax_ub-A_rmax_lb)*rand,2);
                params = [n a p G R];
                params_all(iter,:) = params;

                r1 = R*(sqrt(1+n^2-2*n*cosd(dummy_x))./G).^(1/p);
                    
                if any(r1 > R)
                    s_theta = (-n*sind(x1)*cosd(a)./sqrt(n^2+1-2*n*cosd(x1))...
                               -sind(a)*sqrt((n^2+1-2*n*cosd(x1)-n^2*(sind(x1)).^2)./(n^2+1-2*n*cosd(x1))));
                    L1 = (R*(sqrt(1+n^2-2*n*cosd(x1))./G).^(1/p) <= R).*(R*(sqrt(1+n^2-2*n*cosd(x1))./G).^(1/p).*s_theta);
                    L2 = (R*(G./sqrt(1+n^2-2*n*cosd(x1))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x1))).^(1/p).*s_theta);
                    
                    i_1 = find(y1 <= max(L1));
                    i_2 = find(y1 >= min(L1));
                    i_L1 = intersect(i_1,i_2);
                    i_L2 = [find(y1 >  max(L1)); find(y1 <  min(L1))];
                    
                    err = norm([L1(i_L1)-y1(i_L1); L2(i_L2)-y1(i_L2)]);
                    fun_type(iter) = 1;
                else
                    s_theta = (x1 <= acosd(1/n)).*(-n*sind(x1)*cosd(a)./sqrt(n^2+1-2*n*cosd(x1))...
                                                   -sind(a)*sqrt((n^2+1-2*n*cosd(x1)-n^2*(sind(x1)).^2)./(n^2+1-2*n*cosd(x1))))+...
                              (x1  > acosd(1/n)).*(-n*sind(x1)*cosd(a)./sqrt(n^2+1-2*n*cosd(x1))...
                                                   +sind(a)*sqrt((n^2+1-2*n*cosd(x1)-n^2*(sind(x1)).^2)./(n^2+1-2*n*cosd(x1))));
                    i_360 = find(x1 >= 360-acosd(1/n));
                    s_theta(i_360) = (-n*sind(x1(i_360)-360)*cosd(a)./sqrt(n^2+1-2*n*cosd(x1(i_360)-360))...
                                      -sind(a)*sqrt((n^2+1-2*n*cosd(x1(i_360)-360)-n^2*(sind(x1(i_360)-360)).^2)./(n^2+1-2*n*cosd(x1(i_360)-360))));
                    L2 = (R*(G./sqrt(1+n^2-2*n*cosd(x1))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x1))).^(1/p).*s_theta);

                    err = norm(L2-y1);
                    fun_type(iter) = 2;
                end

                fval_all(iter) = err;
                
                if iter == numIter/10000
                    t_end = toc(t_start);
                    t_est = 10000*(t_end);
                end

                if mod(iter,numIter/10000) == 0
                    d.Value = iter/numIter;
                    d.Message = ['Optimization in progress: ' num2str(round(iter/numIter*100,1)) ' % done; Estimated time: ' num2str(round(t_est/60)) ' min'];
                    drawnow
                end
                
                if d.CancelRequested
                    break
                end
            end
                
            d.Message = 'Optimization complete';
            drawnow
            close(fig)

            [min_fval,fval_minI] = min(fval_all);
            p_min = params_all(fval_minI,:);
            
            n  = p_min(1); a = p_min(2); p = p_min(3); G = p_min(4); R = p_min(5);
            x2 = 0.1:0.1:360-0.1;  
            if fun_type(fval_minI) == 1
                s_theta = (-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                           -sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))));
                y2_f1 = (R*(sqrt(1+n^2-2*n*cosd(x2))./G).^(1/p) <= R).*(R*(sqrt(1+n^2-2*n*cosd(x2))./G).^(1/p).*s_theta);
                y2_f2 = (R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p).*s_theta);
%                 i_x2_min = dsearchn(x2',min(x1));
%                 i_x2_max = dsearchn(x2',max(x1));
                
                cla(app.UIAxes5);
                hold(app.UIAxes5,'on')
                p1 = plot(app.UIAxes5,x1,y1,'.','MarkerSize',Msize,'color',[0, 0.4470, 0.7410]);
                p2 = plot(app.UIAxes5,x2,y2_f1,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
                p3 = plot(app.UIAxes5,x2,y2_f2,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
%                 p3 = plot(app.UIAxes5,x2(i_x2_min:i_x2_max),y2_f2(i_x2_min:i_x2_max),'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
                legend(app.UIAxes5,[p1 p2],{'Tree-falls','Best-fit'},'Location','southeast')
                xlim(app.UIAxes5,[0 400])
                ylim(app.UIAxes5,[min(y1)*1.25 max(y1)*1.25])
                title(app.UIAxes5,'Best-fit #1')
            else
                s_theta = (x2 <= acosd(1/n)).*(-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                                               -sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))))+...
                          (x2  > acosd(1/n)).*(-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                                               +sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))));
                i_360 = find(x2 >= 360-acosd(1/n));
                s_theta(i_360) = (-n*sind(x2(i_360)-360)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2(i_360)-360))...
                                  -sind(a)*sqrt((n^2+1-2*n*cosd(x2(i_360)-360)-n^2*(sind(x2(i_360)-360)).^2)./(n^2+1-2*n*cosd(x2(i_360)-360))));
                y2 = (R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p).*s_theta);                
%                 i_x2_min = dsearchn(x2',min(x1));
%                 i_x2_max = dsearchn(x2',max(x1));

                cla(app.UIAxes5);
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,x1,y1,'.','MarkerSize',Msize,'color',[0, 0.4470, 0.7410])
                plot(app.UIAxes5,x2,y2,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980])
%                 plot(app.UIAxes5,x2(i_x2_min:i_x2_max),y2(i_x2_min:i_x2_max),'LineWidth',2,'color',[0.8500, 0.3250, 0.0980])
                legend(app.UIAxes5,'Tree-falls','Best-fit','Location','southeast') 
                xlim(app.UIAxes5,[0 400])
                ylim(app.UIAxes5,[min(y1)*1.25 max(y1)*1.25])
                title(app.UIAxes5,'Best-fit #1')
            end
                        
            [E_sort,E_sort_i] = sort(fval_all,'ascend');
            p_sort = params_all(E_sort_i,:);

            app.params_a = p_sort;
            app.funtyp_a = fun_type(E_sort_i);
            Vtrans = app.VtransAnalEditField.Value;
                                   
            no = (1:size(E_sort,1))';
            Error = sqrt(E_sort);
            Eta   = p_sort(:,1);
            Gmax  = p_sort(:,4);
            alpha = p_sort(:,2);
            RMW   = p_sort(:,5);
            phi   = p_sort(:,3);
            if Vtrans == 0
                Vcrit = NaN(size(E_sort,1),1);
                Vmax  = NaN(size(E_sort,1),1);
            else
                Vcrit = Eta*Vtrans;
                Vmax  = Vtrans*(Gmax+1);
            end
            
            app.tdata_a = table(no, Error, Eta, Gmax, alpha, RMW, phi, Vcrit, Vmax);
            if size(app.tdata_c,1) >= 1000
                app.UITable_Analytical.Data = app.tdata_a(1:1000,:);
            else
                app.UITable_Analytical.Data = app.tdata_a;
            end
            
            app.Anal_NextPatternButton.Visible = 'on';
            app.Anal_PreviousPatternButton.Visible = 'on';
            app.k = 1;
        end

        % Button pushed function: Anal_NextPatternButton
        function Anal_NextPatternButtonPushed(app, event)
            TFtype = app.ObservedTreefallPatternTypeSwitch.Value;
            y_grid = app.Y_grid;
            
            if strcmp(TFtype,'Not-averaged')
                x1 = app.A_trn;
                y1 = app.Y_trn;
                Msize = 10;                                                 % MarkerSize for UIAxes5
            else
                i_nan = isnan(app.WD_m);
                x1 = app.WD_m(~i_nan);
                y1 = y_grid(~i_nan);
                Msize = 15;                                                 % MarkerSize for UIAxes5
            end           

            p_all = app.params_a;
            f_all = app.funtyp_a;
            app.k = app.k+1;

            if app.k > size(p_all,1)
                msgbox('No more patterns','Error','error')
                app.k = app.k-1;
                return
            end            

            n  = p_all(app.k,1); a = p_all(app.k,2); p = p_all(app.k,3); G = p_all(app.k,4); R = p_all(app.k,5);
            x2 = 0.1:0.1:360-0.1;  
            if f_all(app.k) == 1
                s_theta = (-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                           -sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))));
                y2_f1 = (R*(sqrt(1+n^2-2*n*cosd(x2))./G).^(1/p) <= R).*(R*(sqrt(1+n^2-2*n*cosd(x2))./G).^(1/p).*s_theta);
                y2_f2 = (R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p).*s_theta);
%                 i_x2_min = dsearchn(x2',min(x1));
%                 i_x2_max = dsearchn(x2',max(x1));
                
                cla(app.UIAxes5);
                hold(app.UIAxes5,'on')
                p1 = plot(app.UIAxes5,x1,y1,'.','MarkerSize',Msize,'color',[0, 0.4470, 0.7410]);
                p2 = plot(app.UIAxes5,x2,y2_f1,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
                p3 = plot(app.UIAxes5,x2,y2_f2,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
%                 p3 = plot(app.UIAxes5,x2(i_x2_min:i_x2_max),y2_f2(i_x2_min:i_x2_max),'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
                legend(app.UIAxes5,[p1 p2],{'Tree-falls','Best-fit'},'Location','southeast')
                xlim(app.UIAxes5,[0 400])
                title(app.UIAxes5,['Best-fit #' num2str(app.k)])
            else
                s_theta = (x2 <= acosd(1/n)).*(-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                                               -sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))))+...
                          (x2  > acosd(1/n)).*(-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                                               +sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))));
                i_360 = find(x2 >= 360-acosd(1/n));
                s_theta(i_360) = (-n*sind(x2(i_360)-360)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2(i_360)-360))...
                                  -sind(a)*sqrt((n^2+1-2*n*cosd(x2(i_360)-360)-n^2*(sind(x2(i_360)-360)).^2)./(n^2+1-2*n*cosd(x2(i_360)-360))));
                y2 = (R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p).*s_theta);                
%                 i_x2_min = dsearchn(x2',min(x1));
%                 i_x2_max = dsearchn(x2',max(x1));

                cla(app.UIAxes5);
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,x1,y1,'.','MarkerSize',Msize,'color',[0, 0.4470, 0.7410])
                plot(app.UIAxes5,x2,y2,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980])
%                 plot(app.UIAxes5,x2(i_x2_min:i_x2_max),y2(i_x2_min:i_x2_max),'LineWidth',2,'color',[0.8500, 0.3250, 0.0980])
                legend(app.UIAxes5,'Tree-falls','Best-fit','Location','southeast') 
                xlim(app.UIAxes5,[0 400])
                title(app.UIAxes5,['Best-fit #' num2str(app.k)])
            end
        end

        % Button pushed function: Anal_PreviousPatternButton
        function Anal_PreviousPatternButtonPushed(app, event)
            TFtype = app.ObservedTreefallPatternTypeSwitch.Value;
            y_grid = app.Y_grid;
            
            if strcmp(TFtype,'Not-averaged')
                x1 = app.A_trn;
                y1 = app.Y_trn;
                Msize = 10;                                                 % MarkerSize for UIAxes5
            else
                i_nan = isnan(app.WD_m);
                x1 = app.WD_m(~i_nan);
                y1 = y_grid(~i_nan);
                Msize = 15;                                                 % MarkerSize for UIAxes5
            end           

            p_all = app.params_a;
            f_all = app.funtyp_a;
            
            if app.k <= 1
                msgbox('No more patterns','Error','error')
                return
            end

            app.k = app.k-1;
            
            n  = p_all(app.k,1); a = p_all(app.k,2); p = p_all(app.k,3); G = p_all(app.k,4); R = p_all(app.k,5);
            x2 = 0.1:0.1:360-0.1;  
            if f_all(app.k) == 1
                s_theta = (-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                           -sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))));
                y2_f1 = (R*(sqrt(1+n^2-2*n*cosd(x2))./G).^(1/p) <= R).*(R*(sqrt(1+n^2-2*n*cosd(x2))./G).^(1/p).*s_theta);
                y2_f2 = (R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p).*s_theta);
%                 i_x2_min = dsearchn(x2',min(x1));
%                 i_x2_max = dsearchn(x2',max(x1));
                
                cla(app.UIAxes5);
                hold(app.UIAxes5,'on')
                p1 = plot(app.UIAxes5,x1,y1,'.','MarkerSize',Msize,'color',[0, 0.4470, 0.7410]);
                p2 = plot(app.UIAxes5,x2,y2_f1,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
                p3 = plot(app.UIAxes5,x2,y2_f2,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
%                 p3 = plot(app.UIAxes5,x2(i_x2_min:i_x2_max),y2_f2(i_x2_min:i_x2_max),'LineWidth',2,'color',[0.8500, 0.3250, 0.0980]);
                legend(app.UIAxes5,[p1 p2],{'Tree-falls','Best-fit'},'Location','southeast')
                xlim(app.UIAxes5,[0 400])
                title(app.UIAxes5,['Best-fit #' num2str(app.k)])
            else
                s_theta = (x2 <= acosd(1/n)).*(-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                                               -sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))))+...
                          (x2  > acosd(1/n)).*(-n*sind(x2)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2))...
                                               +sind(a)*sqrt((n^2+1-2*n*cosd(x2)-n^2*(sind(x2)).^2)./(n^2+1-2*n*cosd(x2))));
                i_360 = find(x2 >= 360-acosd(1/n));
                s_theta(i_360) = (-n*sind(x2(i_360)-360)*cosd(a)./sqrt(n^2+1-2*n*cosd(x2(i_360)-360))...
                                  -sind(a)*sqrt((n^2+1-2*n*cosd(x2(i_360)-360)-n^2*(sind(x2(i_360)-360)).^2)./(n^2+1-2*n*cosd(x2(i_360)-360))));
                y2 = (R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p) >  R).*(R*(G./sqrt(1+n^2-2*n*cosd(x2))).^(1/p).*s_theta);                
%                 i_x2_min = dsearchn(x2',min(x1));
%                 i_x2_max = dsearchn(x2',max(x1));

                cla(app.UIAxes5);
                hold(app.UIAxes5,'on')
                plot(app.UIAxes5,x1,y1,'.','MarkerSize',Msize,'color',[0, 0.4470, 0.7410])
                plot(app.UIAxes5,x2,y2,'LineWidth',2,'color',[0.8500, 0.3250, 0.0980])
%                 plot(app.UIAxes5,x2(i_x2_min:i_x2_max),y2(i_x2_min:i_x2_max),'LineWidth',2,'color',[0.8500, 0.3250, 0.0980])
                legend(app.UIAxes5,'Tree-falls','Best-fit','Location','southeast') 
                xlim(app.UIAxes5,[0 400])
                title(app.UIAxes5,['Best-fit #' num2str(app.k)])
            end
        end

        % Button pushed function: ExportTableButton_Anal
        function ExportTableButton_AnalPushed(app, event)
            cpy_folder = uigetdir([],'Choose a folder to export the table');
            
            if isequal(cpy_folder,0)
                return
            end
            
            writetable(app.tdata_a,[cpy_folder '\Treefall_Parameter_Estimation_AnalyticalMethod_' datestr(now,'mmmddHHMM')  '.xlsx'],"AutoFitWidth",false)
        end

        % Cell selection callback: UITable_Analytical
        function UITable_AnalyticalCellSelection(app, event)
            % Retrieve indices of desired parameters from the Analytical table (UITable_Trans)
            indices = event.Indices;
            app.imp_indices_a = indices;            
        end

        % Button pushed function: ImportParametersAnalButton
        function ImportParametersAnalButtonPushed(app, event)
            indices = app.imp_indices_a;
            tNum    = app.TransectNum_Anal.Value;
            if isempty(indices)
                msgbox('Must select a row before inputting the parameters','Error','error')
                return
            elseif size(indices,1) > 1
                if length(unique(indices(:,1))) > 1
                    msgbox('Cannot input more than one row','Error','error')
                    return
                end
            elseif indices(2) ~= 1
                msgbox('Please, select the number in the "No" column','Error','error')
                return
            elseif floor(tNum) ~= ceil(tNum)
                msgbox('Transect # must be an integer','Error','error')
                return
            elseif isempty(app.UITable_TorParameters.Data)
                msgbox('Must define the number of transects in the "Wind Field" tab','Error','error')
                return
            elseif tNum > size(app.UITable_TorParameters.Data,1) 
                msgbox('Transect # is greater than the number of transects in the "Wind Field" tab','Error','error')
                return
            end
            
            Vtrans = app.VtransAnalEditField.Value;
            tdata = app.UITable_Analytical.Data(indices(1),:);
            tdata(1,1) = num2cell(tNum);
            tdata(1,3) = num2cell(Vtrans);
            Tnew = tdata(:,[1,3:7]);

            app.UITable_TorParameters.Data(tNum,:) = Tnew;
        end

        % Selection changed function: 
        % CriticalWindSpeedofTreefallButtonGroup
        function CriticalWindSpeedofTreefallButtonGroupSelectionChanged(app, event)
            % Critical wind speed of tree failure values Vc vs. H.DBH^2 by Mansour et al. (2020)
            % Wind Speed Unit: m/s; H.DBH^2 Unit: m^3;
            HDBH2 = 0.05:0.01:5;
            HDBH2_mean = app.MeanHDBHEditField.Value;            
            HDBH2_std  = app.StdHDBHEditField.Value;
    
            if app.UprootSaturatedButton.Value == 1
                Vc_up_low  = (-12.06*log(HDBH2)+37.489);
                
                Vc_mean   = (-12.06*log(HDBH2_mean)+37.489);
                Vc_std_up = (-12.06*log(HDBH2_mean-HDBH2_std)+37.489);
                Vc_std_dw = (-12.06*log(HDBH2_mean+HDBH2_std)+37.489);
                Vc_std    = Vc_std_up-Vc_mean;
                
                cla(app.UIAxes_VcTree);
                p1 = plot(app.UIAxes_VcTree,HDBH2,Vc_up_low,'Color','Black',"LineWidth",2);
                l1 = line(app.UIAxes_VcTree,[HDBH2_mean HDBH2_mean],[0 Vc_mean],'Color','Blue','LineWidth',2);
                l2 = line(app.UIAxes_VcTree,[HDBH2_mean+HDBH2_std HDBH2_mean+HDBH2_std],[0 Vc_mean-Vc_std],'LineStyle','--','Color','Blue');
                l3 = line(app.UIAxes_VcTree,[HDBH2_mean-HDBH2_std HDBH2_mean-HDBH2_std],[0 Vc_mean+Vc_std],'LineStyle','--','Color','Blue');
                l4 = line(app.UIAxes_VcTree,[0 HDBH2_mean],[Vc_mean Vc_mean],'Color','Red','LineWidth',2);
                l5 = line(app.UIAxes_VcTree,[0 HDBH2_mean+HDBH2_std],[Vc_std_dw Vc_std_dw],'LineStyle','--','Color','Red');
                l6 = line(app.UIAxes_VcTree,[0 HDBH2_mean-HDBH2_std],[Vc_std_up Vc_std_up],'LineStyle','--','Color','Red');
                legend(app.UIAxes_VcTree,[p1 l1 l2 l4 l5],'Uproot (Saturated)','Mean H.DBH^2','+/-SD H.DBH^2','Mean Vcrit','+/-SD Vcrit','Location','Northeast');
                axis(app.UIAxes_VcTree,[0 5 0 140])
                app.MeanVcTreeLabel.Text = ['Mean Vcrit: ' num2str(round(Vc_mean,1)) ' m/s'];
                drawnow
                app.StdVcTreeLabel.Text = ['Std Vcrit: ' num2str(round(Vc_std,1)) ' m/s'];
                drawnow
            elseif app.UprootUnsaturatedButton.Value == 1
                Vc_up_high = (-20.05*log(HDBH2)+68.509);

                Vc_mean = (-20.05*log(HDBH2_mean)+68.509);
                Vc_std_up = (-20.05*log(HDBH2_mean-HDBH2_std)+68.509);
                Vc_std_dw = (-20.05*log(HDBH2_mean+HDBH2_std)+68.509);
                Vc_std    = Vc_std_up-Vc_mean;

                cla(app.UIAxes_VcTree);
                p1 = plot(app.UIAxes_VcTree,HDBH2,Vc_up_high,'Color','Black',"LineWidth",2);
                l1 = line(app.UIAxes_VcTree,[HDBH2_mean HDBH2_mean],[0 Vc_mean],'Color','Blue','LineWidth',2);
                l2 = line(app.UIAxes_VcTree,[HDBH2_mean+HDBH2_std HDBH2_mean+HDBH2_std],[0 Vc_mean-Vc_std],'LineStyle','--','Color','Blue');
                l3 = line(app.UIAxes_VcTree,[HDBH2_mean-HDBH2_std HDBH2_mean-HDBH2_std],[0 Vc_mean+Vc_std],'LineStyle','--','Color','Blue');
                l4 = line(app.UIAxes_VcTree,[0 HDBH2_mean],[Vc_mean Vc_mean],'Color','Red','LineWidth',2);
                l5 = line(app.UIAxes_VcTree,[0 HDBH2_mean+HDBH2_std],[Vc_std_dw Vc_std_dw],'LineStyle','--','Color','Red');
                l6 = line(app.UIAxes_VcTree,[0 HDBH2_mean-HDBH2_std],[Vc_std_up Vc_std_up],'LineStyle','--','Color','Red');
                legend(app.UIAxes_VcTree,[p1 l1 l2 l4 l5],'Uproot (Unsaturated)','Mean H.DBH^2','+/-SD H.DBH^2','Mean Vcrit','+/-SD Vcrit','Location','Northeast');
                axis(app.UIAxes_VcTree,[0 5 0 140])
                app.MeanVcTreeLabel.Text = ['Mean Vcrit: ' num2str(round(Vc_mean,1)) ' m/s'];
                drawnow
                app.StdVcTreeLabel.Text = ['Std Vcrit: ' num2str(round(Vc_std,1)) ' m/s'];
                drawnow
            elseif app.StemBreakageKnot075Button.Value == 1
                Vc_st_low  = (48.958*HDBH2.^0.0748);

                Vc_mean = (48.958*(HDBH2_mean)^0.0748);
                Vc_std_up = (48.958*(HDBH2_mean+HDBH2_std)^0.0748);
                Vc_std_dw = (48.958*(HDBH2_mean-HDBH2_std)^0.0748);
                Vc_std    = Vc_std_up-Vc_mean;

                cla(app.UIAxes_VcTree);
                p1 = plot(app.UIAxes_VcTree,HDBH2,Vc_st_low,'Color','Black',"LineWidth",2);
                l1 = line(app.UIAxes_VcTree,[HDBH2_mean HDBH2_mean],[0 Vc_mean],'Color','Blue','LineWidth',2);
                l2 = line(app.UIAxes_VcTree,[HDBH2_mean+HDBH2_std HDBH2_mean+HDBH2_std],[0 Vc_mean-Vc_std],'LineStyle','--','Color','Blue');
                l3 = line(app.UIAxes_VcTree,[HDBH2_mean-HDBH2_std HDBH2_mean-HDBH2_std],[0 Vc_mean+Vc_std],'LineStyle','--','Color','Blue');
                l4 = line(app.UIAxes_VcTree,[0 HDBH2_mean],[Vc_mean Vc_mean],'Color','Red','LineWidth',2);
                l5 = line(app.UIAxes_VcTree,[0 HDBH2_mean+HDBH2_std],[Vc_std_up Vc_std_up],'LineStyle','--','Color','Red');
                l6 = line(app.UIAxes_VcTree,[0 HDBH2_mean-HDBH2_std],[Vc_std_dw Vc_std_dw],'LineStyle','--','Color','Red');
                legend(app.UIAxes_VcTree,[p1 l1 l2 l4 l5],'Stem Breakage (Knot = 0.75)','Mean H.DBH^2','+/-SD H.DBH^2','Mean Vcrit','+/-SD Vcrit','Location','Northeast');
                axis(app.UIAxes_VcTree,[0 5 0 140])
                app.MeanVcTreeLabel.Text = ['Mean Vcrit: ' num2str(round(Vc_mean,1)) ' m/s'];
                drawnow
                app.StdVcTreeLabel.Text = ['Std Vcrit: ' num2str(round(Vc_std,1)) ' m/s'];
                drawnow
            elseif app.StemBreakageKnot10Button.Value == 1
                Vc_st_high = (56.534*HDBH2.^0.0750);
                
                Vc_mean = (56.534*(HDBH2_mean)^0.0750);
                Vc_std_up = (56.534*(HDBH2_mean+HDBH2_std)^0.0750);
                Vc_std_dw = (56.534*(HDBH2_mean-HDBH2_std)^0.0750);
                Vc_std    = Vc_std_up-Vc_mean;

                cla(app.UIAxes_VcTree);
                p1 = plot(app.UIAxes_VcTree,HDBH2,Vc_st_high,'Color','Black',"LineWidth",2);
                l1 = line(app.UIAxes_VcTree,[HDBH2_mean HDBH2_mean],[0 Vc_mean],'Color','Blue','LineWidth',2);
                l2 = line(app.UIAxes_VcTree,[HDBH2_mean+HDBH2_std HDBH2_mean+HDBH2_std],[0 Vc_mean-Vc_std],'LineStyle','--','Color','Blue');
                l3 = line(app.UIAxes_VcTree,[HDBH2_mean-HDBH2_std HDBH2_mean-HDBH2_std],[0 Vc_mean+Vc_std],'LineStyle','--','Color','Blue');
                l4 = line(app.UIAxes_VcTree,[0 HDBH2_mean],[Vc_mean Vc_mean],'Color','Red','LineWidth',2);
                l5 = line(app.UIAxes_VcTree,[0 HDBH2_mean+HDBH2_std],[Vc_std_up Vc_std_up],'LineStyle','--','Color','Red');
                l6 = line(app.UIAxes_VcTree,[0 HDBH2_mean-HDBH2_std],[Vc_std_dw Vc_std_dw],'LineStyle','--','Color','Red');
                legend(app.UIAxes_VcTree,[p1 l1 l2 l4 l5],'Stem Breakage (Knot = 1.0)','Mean H.DBH^2','+/-SD H.DBH^2','Mean Vcrit','+/-SD Vcrit','Location','Northeast');
                axis(app.UIAxes_VcTree,[0 5 0 140])
                app.MeanVcTreeLabel.Text = ['Mean Vcrit: ' num2str(round(Vc_mean,1)) ' m/s'];
                drawnow
                app.StdVcTreeLabel.Text = ['Std Vcrit: ' num2str(round(Vc_std,1)) ' m/s'];
                drawnow
            end
        end

        % Value changed function: CornGrowthStageSlider
        function CornGrowthStageSliderValueChanged(app, event)
            GF = 1+0.42*0.52*log(3600/3);

            U_mean = [12.07 8.71 8.32 5.84 7.78 8.32 5.60 6.05];
            U_std  = [ 2.25 1.99 1.30 0.70 0.87 0.93 1.13 1.46];
    
            cla(app.UIAxes_VcCorn);
            errorbar(app.UIAxes_VcCorn,1:8,U_mean*GF,U_std*GF,'ok')
            axis(app.UIAxes_VcCorn,[0.5 8.5 10 40])            
            xticklabels(app.UIAxes_VcCorn,{'V9','V12','V13','R1-2','R3-4','R4-5','R5-6','R6'})

            value = app.CornGrowthStageSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            
            switch event.Source.Value
                case 1
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,1,U_mean(1)*GF,U_std(1)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','V9');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(1)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(1)*GF,1)) ' m/s'];
                    drawnow
                case 2
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,2,U_mean(2)*GF,U_std(2)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','V12');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(2)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(2)*GF,1)) ' m/s'];
                    drawnow
                case 3
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,3,U_mean(3)*GF,U_std(3)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','V13');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(3)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(3)*GF,1)) ' m/s'];
                    drawnow
                case 4
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,4,U_mean(4)*GF,U_std(4)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','R1-2');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(4)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(4)*GF,1)) ' m/s'];
                    drawnow
                case 5
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,5,U_mean(5)*GF,U_std(5)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','R3-4');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(5)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(5)*GF,1)) ' m/s'];
                    drawnow
                case 6
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,6,U_mean(6)*GF,U_std(6)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','R4-5');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(6)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(6)*GF,1)) ' m/s'];
                    drawnow
                case 7
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,7,U_mean(7)*GF,U_std(7)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','R5-6');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(7)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(7)*GF,1)) ' m/s'];
                    drawnow
                case 8
                    hold(app.UIAxes_VcCorn,'on')
                    errorbar(app.UIAxes_VcCorn,8,U_mean(8)*GF,U_std(8)*GF,'or')
                    ylim(app.UIAxes_VcCorn,[0 40])
                    legend(app.UIAxes_VcCorn,'Mean +/-SD','R6');
                    app.MeanVcCornLabel.Text = ['Mean Vcrit: ' num2str(round(U_mean(8)*GF,1)) ' m/s'];
                    drawnow
                    app.StdVcCornLabel.Text = ['Std Vcrit: ' num2str(round(U_std(8)*GF,1)) ' m/s'];
                    drawnows
            end
        end

        % Button pushed function: SelectFileButton_TC
        function SelectFileButton_TCPushed(app, event)
            [xlsx_file,path] = uigetfile({'*.xlsx';'*.xls'},'Select .xlsx file with tornado center');
            if isequal(xlsx_file,0)
                app.CurrentFileEditField_TC.Value = 'No file detected';
            else
                app.CurrentFileEditField_TC.Value = xlsx_file;
            end
            
            app.filename_tc = xlsx_file;
            app.filepath_tc = path;
        end

        % Button pushed function: ShowtornadotrackButton
        function ShowtornadotrackButtonPushed(app, event)
            if isempty(app.filename_tc)
                msgbox('No file detected');
                return
            end
            
            value = app.NumberofTransectsEditField.Value;

            % Pop-up error message if Number of Transects is zero
            if value == 0
                msgbox('Number of Transects must be greater than zero','Error','error')
                return
            end

            %% Take geolocation (LAT,LON) and map tornado track
            % Tak tornado track (LAT,LON) from excel file 
            xlsx_file = app.filename_tc;
            path      = app.filepath_tc;
            sheetnum  = app.ExcelSheetNumberEditField_TC.Value;
            if floor(sheetnum) ~= ceil(sheetnum)
                msgbox('Excel Sheet Number must be an integer','Error','error')
                return
            end
            
            LON_tc_chr = app.TorTrackLON_EditField.Value;
            LAT_tc_chr = app.TorTrackLAT_EditField.Value;
            LON_tc = xlsread([path xlsx_file],sheetnum,char(LON_tc_chr));       %    Tornado track coordinates (longitude)
            LAT_tc = xlsread([path xlsx_file],sheetnum,char(LAT_tc_chr));       %    Tornado track coordinates (latitude)

            % Take geolocation (LAT,LON) of transects from UITable_Transects
            tdata = app.UITable_Transects.Data;
            lat = table2array(tdata(:,2));
            lon = table2array(tdata(:,3));

            % Mapping out tornado track on geoaxes (TornadoTrackPanel)
            delete(app.map)
            app.map = geoaxes(app.TornadoTrackPanel);
            geoplot(app.map,LAT_tc,LON_tc,'--','LineWidth',2)
            hold(app.map,'on')
            geoplot(app.map,lat,lon,'o','MarkerSize',10,'LineWidth',2)
            legend(app.map,'Tornado Track','Transects')
        end

        % Value changed function: NumberofTransectsEditField
        function NumberofTransectsEditFieldValueChanged(app, event)
            value = app.NumberofTransectsEditField.Value;
            
            % Populate zero numbers in tornado track table (UITable_TorTrack)
            N = value;                  % Number of transects specified in NumberofTransectsEditField
            Trans_number = (1:N)';
            latitude  = zeros(N,1);
            longitude = zeros(N,1);

            tdata1 = table(Trans_number, latitude, longitude);
            app.UITable_Transects.Data = tdata1;

            % Populate zero numbers in parameter table (UITable_TorParameters)
            Vt    = zeros(N,1);
            Gmax  = zeros(N,1);
            alpha = zeros(N,1);
            RMW   = zeros(N,1);
            phi   = zeros(N,1);
            
            tdata2 = table(Trans_number, Vt, Gmax, alpha, RMW, phi);
            app.UITable_TorParameters.Data = tdata2;
        end

        % Value changed function: VtransAnalEditField
        function VtransAnalEditFieldValueChanged(app, event)
            Vtrans = app.VtransAnalEditField.Value;
            Vcrit = table2array(app.UITable_Analytical.Data(:,3))*Vtrans;
            Vmax  = (table2array(app.UITable_Analytical.Data(:,4))+1)*Vtrans;
            tdata = table(Vcrit,Vmax);
            
            % Change Vcrit & Vmax column value as Vtrans value is changed
            app.UITable_Analytical.Data(:,8:9) = tdata;
        end

        % Button pushed function: GenerateWindFieldButton
        function GenerateWindFieldButtonPushed(app, event)
            switch app.WindFieldModelDropDown.Value
                case 'Rankine Vortex'
                    WFmodel = 1;
                case 'Burgers-Rott Vortex'
                    WFmodel = 2;
                case 'Sullivan Vortex'
                    WFmodel = 3;
                case 'Modified Sullivan Vortex'
                    WFmodel = 4;
            end
            
            switch app.TranslationModelDropDown.Value
                case 'Constant Translation'
                    TSmodel = 1;
                case 'Decaying Translation'
                    TSmodel = 2;
            end
            
            if isempty(app.filename_tc)
                msgbox('No file detected');
                return
            elseif app.TransectNum_Anal.Value == 0
                msgbox('Transect # must be greater than zero','Error','error')
                return
            else
                if WFmodel ~= 1 && TSmodel == 1
                    if sum(isnan(table2array(app.UITable_TorParameters.Data(:,6)))) ~= app.TransectNum_Anal.Value
                        answer1 = questdlg('phi should be NaN for selected models. Would you still like to proceed?','Warning','Yes','No','');
                        switch answer1
                            case 'No'
                                return
                        end
                    end
                else
                    if sum(isnan(table2array(app.UITable_TorParameters.Data(:,6)))) > 0
                        msgbox('phi cannot be NaN for selected models','Error','error')
                        return
                    end
                end
                
                answer = questdlg('Wind field generation process may take a long time. Would you like to proceed?','Warning','Yes','No','');
                switch answer
                    case 'No'
                        return
                end
                
                % Final excel output save directory
                folder_save = uigetdir('Select the folder you wish to save the wind field excel file');
                if isequal(folder_save,0)
                    msgbox('Must select a folder to save the wind field file','Error','error')                    
                    return
                end
                
                % Input setting for wind field generation
                definput = {'10','Tornado_Wind_Field.xlsx'};
                prompt   = {'Enter wind field grid spacing (m): ','Enter the excel file name that you wish to save: '};
                input    = inputdlg(prompt,'Wind Field Generation',[1 50],definput);
                
                if isempty(input)
                    msgbox('Wind field production canceled','Error','error')                    
                    return
                end                
                gs = str2double(char(input(1)));
                
                if gs <= 0 || isnan(gs) 
                    msgbox('Grid spacing must be greater than zero','Error','error')
                    return                    
                end
                file_save = char(input(2));

                TPdata = app.UITable_TorParameters.Data;
                TTdata = app.UITable_Transects.Data;

                lat_trans = table2array(TTdata(:,2));
                lon_trans = table2array(TTdata(:,3));            
                params = table2array(TPdata(:,2:end));
            
                % Geographic coordinate to projected coordinate conversion
                dczone = utmzone(lat_trans,lon_trans);          %   Identify UTM zone
                utmstruct = defaultm('utm');
                utmstruct.zone = dczone;
                utmstruct.geoid = wgs84Ellipsoid;
                utmstruct = defaultm(utmstruct);           
            
                % Tornado track coordinates
                xlsx_file = app.filename_tc;
                path      = app.filepath_tc;
                sheetnum  = app.ExcelSheetNumberEditField_TC.Value;
            
                LON_tc_chr = app.TorTrackLON_EditField.Value;
                LAT_tc_chr = app.TorTrackLAT_EditField.Value;
                LON_tc = xlsread([path xlsx_file],sheetnum,char(LON_tc_chr));       %   Tornado track coordinates (longitude)
                LAT_tc = xlsread([path xlsx_file],sheetnum,char(LAT_tc_chr));       %   Tornado track coordinates (latitude)

                [X_tc,Y_tc] = projfwd(utmstruct,LAT_tc,LON_tc);
                [X,Y] = meshgrid((min(X_tc)-app.DW):gs:(max(X_tc)+app.DW),(min(Y_tc)-app.DW):gs:(max(Y_tc)+app.DW));    %   NEEEEEEED MORE THOUGHTS/FIXING
                X_l = X(:);
                Y_l = Y(:);
            
                % Convert transects coordinates to projected coordinates
                [X_trans,~] = projfwd(utmstruct,lat_trans,lon_trans);

                % Tornado simulation initial setup
                r = 0:1:7000;                       %   Populate radius for windfield model
                time_j = 0;                         %   Initialize time            
                tor_inc_x = gs/5;                   %   Tornado transation increment
                tor_stp_x = [];                     %   Tornado steps in x-dir
                tor_stp_y = [];                     %   Tornado steps in y-dir

                for j = 1:length(X_tc)-1
                    tor_stp_x  = [tor_stp_x X_tc(j):tor_inc_x:X_tc(j+1)];
                    trns_angle = atand((Y_tc(j+1)-Y_tc(j))/(X_tc(j+1)-X_tc(j)));
                    tor_stp_y  = [tor_stp_y Y_tc(j):tor_inc_x*tand(trns_angle):Y_tc(j+1)];
                end

                num_step = length(tor_stp_x);                       %   Number of steps for tornado to translate through
                V = NaN(size(r));
                time = NaN(1,num_step-1);
                V_total  = NaN(length(time),length(X_l));
                wd_total = NaN(length(time),length(X_l));

                fig = uifigure;
                d = uiprogressdlg(fig,'Title','Please wait ...','Message','Wind field production in progress: 0% done','Cancelable','on');
                drawnow

                t_start = tic;
                for ii = 1:num_step-1

                    % Parameter Change
                    if size(params,1) == 1
                        Vt    = params(1,1);
                        Gmax  = params(1,2);
                        alpha = params(1,3);
                        Rmax  = params(1,4);
                        phi   = params(1,5);
                    else
                        for jj = 1:size(params,1)
                            if jj ~= size(params,1)
                                if tor_stp_x(ii) < mean([X_trans(jj),X_trans(jj+1)])
                                    Vt    = params(jj,1);
                                    Gmax  = params(jj,2);
                                    alpha = params(jj,3);
                                    Rmax  = params(jj,4);
                                    phi   = params(jj,5);
                                    break
                                end
                            elseif tor_stp_x(ii) > mean([X_trans(end-1),X_trans(end)])
                                Vt    = params(end,1);
                                Gmax  = params(end,2);
                                alpha = params(end,3);
                                Rmax  = params(end,4);
                                phi   = params(end,5);
                            end
                        end
                    end

                    % Rankine Vortex Model
                    Vmx = Vt*Gmax;
                    if WFmodel == 1
                        for i = 1:length(r)
                            if r(i)<=Rmax
                                V(i) = Vmx*(r(i)/Rmax)^phi;
                            else
                                V(i) = Vmx*(Rmax/r(i))^phi;
                            end
                        end
                    elseif WFmodel == 2
                            V = 1.4*(Vmx*(r./Rmax).^-1).*(1-exp(-1.2564*(r./Rmax).^2));
                    elseif WFmodel == 3
                            V = Vmx*((r./Rmax).^2.4).*(0.3+0.7*(r./Rmax).^7.89).^-0.435;
                    elseif WFmodel == 4
                            V = Vmx*((r./Rmax).^10).*(0.091+0.909*(r./Rmax).^22).^-0.5;
                    end

                    % Rotation Angle Change
                    rot_angle = atand((tor_stp_y(ii+1)-tor_stp_y(ii))/(tor_stp_x(ii+1)-tor_stp_x(ii)));
                    tor_inc_y = tor_inc_x*tand(rot_angle);                  %   Increment of motion of tornado in y-direction
                    time_step = (sqrt(tor_inc_y^2+tor_inc_y^2))*(1/Vt);     %   Time in secs
                    time_j   = time_j + time_step;
                    time(ii) = time_j;

                    % Translational Speed
                    if TSmodel == 1
                        Vtx = Vt*cosd(rot_angle);           %   x-comp of tornado translational speed
                        Vty = Vt*sind(rot_angle);           %   y-comp of tornado translational speed
                    else
                        for i = 1:length(r)
                            if r(i)<=Rmax
                                Vt_all(i) = Vt;
                            else
                                Vt_all(i) = Vt*(Rmax/r(i))^phi;
                            end
                        end
                    end
                    
                    X_dist = X - tor_stp_x(ii);         %   Tornado x-dist from grid points
                    Y_dist = Y - tor_stp_y(ii);         %   Tornado y-dist from grid points    
    
                    R_tree = sqrt((X_dist.^2) + (Y_dist.^2)); %tornado radius from grid points (trees)
    
                    X_dist_l = X_dist(:);
                    Y_dist_l = Y_dist(:);
                    R_tree_l = R_tree(:);
                    k_l = find(R_tree_l <= 7000);

                    angle_one = acosd(abs(X_dist_l)./R_tree_l); %angle from x-axis to radial line connecting tornado location and grid point
                
                    for kk = 1:length(R_tree_l) % loop thru each grid point (tree)
                       
                        R_tree_k = R_tree_l(kk);
                        wind_ind = dsearchn(r',R_tree_k);
                        ws = V(wind_ind); %find Rankine wind speed grid point
                        if TSmodel == 2
                            Vt_ws = Vt_all(wind_ind); %find translational wind speed grid point
                            Vtx = Vt_ws*cosd(rot_angle);
                            Vty = Vt_ws*sind(rot_angle);
                        end
                        
                        Xd = X_dist_l(kk); %x-dist of grid point
                        Yd = Y_dist_l(kk); %y-dist of grid point
            
                        if ismember(kk,k_l)
                            if isnan(angle_one(kk))
                                u_comp = Vtx;
                                v_comp = Vty;                
                            else
                                if Xd <= 0 && Yd <= 0
                                    tot_angle = angle_one(kk)- alpha;
                                    u_comp = ws*cosd(tot_angle) + Vtx;
                                    v_comp = ws*sind(tot_angle) + Vty;                
                                elseif Xd <= 0 && Yd >= 0
                                    tot_angle = angle_one(kk)+ alpha;
                                    u_comp =   ws*cosd(tot_angle) + Vtx;
                                    v_comp = -[ws*sind(tot_angle)] + Vty;
                                elseif Xd >= 0 && Yd >= 0
                                    tot_angle = angle_one(kk)- alpha;
                                    u_comp = -[ws*cosd(tot_angle)] + Vtx;
                                    v_comp = -[ws*sind(tot_angle)] + Vty;
                                elseif Xd >= 0 && Yd <= 0
                                    tot_angle = angle_one(kk)+ alpha;
                                    u_comp = -[ws*cosd(tot_angle)] + Vtx;
                                    v_comp = ws*sind(tot_angle)+Vty;
                                end
                            end 
                        else
                           u_comp = Vtx;
                           v_comp = Vty;
                        end
        
                        if u_comp >= 0 && v_comp >= 0
                            wd = 270-atand(v_comp/u_comp);
                        elseif u_comp >= 0 && v_comp <= 0
                            wd = 270+abs(atand(v_comp/u_comp));
                        elseif u_comp <= 0 && v_comp <= 0
                            wd = 90-atand(v_comp/u_comp);
                        elseif u_comp <= 0 && v_comp >= 0
                            wd = 90+abs(atand(v_comp/u_comp));
                        end 
            
                        V_tot = sqrt((u_comp^2)+(v_comp^2));
                        V_total(ii,kk)  = V_tot;
                        wd_total(ii,kk) = wd;

                    end

                    if ii == 1
                        t_est1 = toc(t_start);
                        t_est  = t_est1*(num_step-1);
                    end
                
                    d.Value = ii/(num_step-1);
                    d.Message = ['Wind field production in progress: ' num2str(round(ii/(num_step-1)*100)) ' % done; Estimated time: ' num2str(round(t_est/60)) ' min'];
                    drawnow
                
                    if d.CancelRequested
                        break
                    end
                
                end
                
                V_t = max(V_total)';
                [lat,lon] = projinv(utmstruct,X_l,Y_l);
                no = (1:length(lat))';
                data = table(no,lat,lon,V_t);
                filename = [folder_save '\' file_save];
                writetable(data,filename,"AutoFitWidth",false)
            
                d.Message = 'Optimization complete';
                drawnow
                close(fig)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TreefallAnalysisSoftwareLabel = uilabel(app.UIFigure);
            app.TreefallAnalysisSoftwareLabel.Text = 'Tree-fall Analysis Software';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'NTP_logo.jpg';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InitialSetupTab = uitab(app.TabGroup);
            app.InitialSetupTab.Title = 'Initial Setup';
            app.SelectFileButton = uibutton(app.InitialSetupTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.CurrentFileEditFieldLabel = uilabel(app.InitialSetupTab);
            app.CurrentFileEditFieldLabel.Text = 'Current File:';
            app.CurrentFileEditField = uieditfield(app.InitialSetupTab, 'text');
            app.InputTreefallCoordinatesPanel = uipanel(app.InitialSetupTab);
            app.InputTreefallCoordinatesPanel.Title = 'Input Tree-fall Coordinates';
            app.InputLabel_Tree = uilabel(app.InputTreefallCoordinatesPanel);
            app.InputLabel_Tree.Text = 'Enter the rows of tree-fall coordinates in the format of (X0:X00000)';
            app.STARTINGPOINTLATITUDEEditField_3Label = uilabel(app.InputTreefallCoordinatesPanel);
            app.STARTINGPOINTLATITUDEEditField_3Label.Text = 'STARTING POINT LATITUDE:';
            app.StartLAT_EditField_Tree = uieditfield(app.InputTreefallCoordinatesPanel, 'text');
            app.StartLAT_EditField_Tree.Value = 'A1:A10000';
            app.STARTINGPOINTLONGITUDEEditField_3Label = uilabel(app.InputTreefallCoordinatesPanel);
            app.STARTINGPOINTLONGITUDEEditField_3Label.Text = 'STARTING POINT LONGITUDE:';
            app.StartLON_EditField_Tree = uieditfield(app.InputTreefallCoordinatesPanel, 'text');
            app.StartLON_EditField_Tree.Value = 'B1:B10000';
            app.ENDINGPOINTLATITUDEEditField_3Label = uilabel(app.InputTreefallCoordinatesPanel);
            app.ENDINGPOINTLATITUDEEditField_3Label.Text = 'ENDING POINT LATITUDE:';
            app.EndLAT_EditField_Tree = uieditfield(app.InputTreefallCoordinatesPanel, 'text');
            app.EndLAT_EditField_Tree.Value = 'C1:C10000';
            app.ENDINGPOINTLONGITUDEEditField_3Label = uilabel(app.InputTreefallCoordinatesPanel);
            app.ENDINGPOINTLONGITUDEEditField_3Label.Text = 'ENDING POINT LONGITUDE:';
            app.EndLON_EditField_Tree = uieditfield(app.InputTreefallCoordinatesPanel, 'text');
            app.EndLON_EditField_Tree.Value = 'D1:D10000';
            app.TREEFALLDIRECTIONLabel = uilabel(app.InputTreefallCoordinatesPanel);
            app.TREEFALLDIRECTIONLabel.Text = 'TREE-FALL DIRECTION:';
            app.TreeDir_EditField = uieditfield(app.InputTreefallCoordinatesPanel, 'text');
            app.TreeDir_EditField.Value = 'E1:E10000';
            app.InputLabel_Tree_2 = uilabel(app.InputTreefallCoordinatesPanel);
            app.InputLabel_Tree_2.Text = 'or';
            app.TreefallDirectionSwitchLabel = uilabel(app.InputTreefallCoordinatesPanel);
            app.TreefallDirectionSwitchLabel.Text = 'Tree-fall Direction';
            app.TreefallDirectionSwitch = uiswitch(app.InputTreefallCoordinatesPanel, 'slider');
            app.TreefallDirectionSwitch.Items = {'Needs Calculation', 'Already Calculated'};
            app.TreefallDirectionSwitch.Value = 'Needs Calculation';
            app.InputCompleteButton = uibutton(app.InputTreefallCoordinatesPanel, 'push');
            app.InputCompleteButton.ButtonPushedFcn = createCallbackFcn(app, @InputCompleteButtonPushed, true);
            app.InputCompleteButton.Text = 'Input Complete';
            app.InputTornadoPathPanel = uipanel(app.InitialSetupTab);
            app.InputTornadoPathPanel.Title = 'Input Tornado Path';
            app.InputLabel_TC = uilabel(app.InputTornadoPathPanel);
            app.InputLabel_TC.Text = 'Enter the geographic coordinates of estimated tornado center';
            app.STARTINGPOINTLATITUDEEditField_2Label = uilabel(app.InputTornadoPathPanel);
            app.STARTINGPOINTLATITUDEEditField_2Label.Text = 'STARTING POINT LATITUDE:';
            app.StartLAT_EditField_TC = uieditfield(app.InputTornadoPathPanel, 'numeric');
            app.StartLAT_EditField_TC.ValueDisplayFormat = '%.8f';
            app.StartLAT_EditField_TC.Value = 32.474832;
            app.STARTINGPOINTLONGITUDEEditField_2Label = uilabel(app.InputTornadoPathPanel);
            app.STARTINGPOINTLONGITUDEEditField_2Label.Text = 'STARTING POINT LONGITUDE:';
            app.StartLON_EditField_TC = uieditfield(app.InputTornadoPathPanel, 'numeric');
            app.StartLON_EditField_TC.ValueDisplayFormat = '%.8f';
            app.StartLON_EditField_TC.Value = -85.371624;
            app.ENDINGPOINTLATITUDEEditField_2Label = uilabel(app.InputTornadoPathPanel);
            app.ENDINGPOINTLATITUDEEditField_2Label.Text = 'ENDING POINT LATITUDE:';
            app.EndLAT_EditField_TC = uieditfield(app.InputTornadoPathPanel, 'numeric');
            app.EndLAT_EditField_TC.ValueDisplayFormat = '%.8f';
            app.EndLAT_EditField_TC.Value = 32.475392;
            app.ENDINGPOINTLONGITUDEEditField_2Label = uilabel(app.InputTornadoPathPanel);
            app.ENDINGPOINTLONGITUDEEditField_2Label.Text = 'ENDING POINT LONGITUDE:';
            app.EndLON_EditField_TC = uieditfield(app.InputTornadoPathPanel, 'numeric');
            app.EndLON_EditField_TC.ValueDisplayFormat = '%.8f';
            app.EndLON_EditField_TC.Value = -85.370228;
            app.ObservedTreefallPatternPanel = uipanel(app.InitialSetupTab);
            app.ObservedTreefallPatternPanel.Title = 'Observed Tree-fall Pattern';
            app.UIAxes = uiaxes(app.ObservedTreefallPatternPanel);
            xlabel(app.UIAxes, 'X distance (m)')
            ylabel(app.UIAxes, 'Y distance (m)')
            app.UIAxes2 = uiaxes(app.ObservedTreefallPatternPanel);
            xlabel(app.UIAxes2, 'X distance (m)')
            ylabel(app.UIAxes2, 'Y distance (m)')
            app.ENDINGPOINTLATITUDEEditField_2Label_2 = uilabel(app.InitialSetupTab);
            app.ENDINGPOINTLATITUDEEditField_2Label_2.Text = 'Excel Sheet Number:';
            app.ExcelSheetNumber = uieditfield(app.InitialSetupTab, 'numeric');
            app.ExcelSheetNumber.Limits = [1 Inf];
            app.ExcelSheetNumber.Value = 1;
            app.WindFieldModelButtonGroup = uibuttongroup(app.InitialSetupTab);
            app.WindFieldModelButtonGroup.Title = 'Wind Field Model';
            app.Rankine = uiradiobutton(app.WindFieldModelButtonGroup);
            app.Rankine.Text = 'Rankine Vortex';
            app.Rankine.Value = true;
            app.BurgersRott = uiradiobutton(app.WindFieldModelButtonGroup);
            app.BurgersRott.Text = 'Burgers-Rott Vortex';
            app.Sullivan = uiradiobutton(app.WindFieldModelButtonGroup);
            app.Sullivan.Text = 'Sullivan Vortex';
            app.InputLabel_WindField = uilabel(app.WindFieldModelButtonGroup);
            app.InputLabel_WindField.Text = 'Select the desired wind field model';
            app.ModifiedSullivan = uiradiobutton(app.WindFieldModelButtonGroup);
            app.ModifiedSullivan.Text = 'Modified Sullivan Vortex';
            app.TranslationModelButtonGroup = uibuttongroup(app.InitialSetupTab);
            app.TranslationModelButtonGroup.Title = 'Translation Model';
            app.ConstantTranslation = uiradiobutton(app.TranslationModelButtonGroup);
            app.ConstantTranslation.Text = 'Constant Translation';
            app.ConstantTranslation.Value = true;
            app.DecayingTranslation = uiradiobutton(app.TranslationModelButtonGroup);
            app.DecayingTranslation.Text = 'Decaying Translation';
            app.InputLabel_Translation = uilabel(app.TranslationModelButtonGroup);
            app.InputLabel_Translation.Text = 'Select translation model';
            app.CatalogMethodTab = uitab(app.TabGroup);
            app.CatalogMethodTab.Title = 'Catalog Method';
            app.CatalogMethod = uitabgroup(app.CatalogMethodTab);
            app.SimulatedTreefallPatternTab = uitab(app.CatalogMethod);
            app.SimulatedTreefallPatternTab.Title = 'Simulated Tree-fall Pattern';
            app.UIAxes6_5 = uiaxes(app.SimulatedTreefallPatternTab);
            app.UIAxes6_4 = uiaxes(app.SimulatedTreefallPatternTab);
            app.UIAxes6_3 = uiaxes(app.SimulatedTreefallPatternTab);
            app.UIAxes6_2 = uiaxes(app.SimulatedTreefallPatternTab);
            app.UIAxes6_1 = uiaxes(app.SimulatedTreefallPatternTab);
            ylabel(app.UIAxes6_1, 'Y distance (m)')
            app.Catalog_InputLabel_Bounds = uilabel(app.SimulatedTreefallPatternTab);
            app.Catalog_InputLabel_Bounds.Text = 'Enter bounds of each parameter (Top row: upperbound, bottom row: lowerbound)';
            app.Catalog_InputLabel_Vtrans = uilabel(app.SimulatedTreefallPatternTab);
            app.Catalog_InputLabel_Vtrans.Text = 'Vtrans (m/s)';
            app.Catalog_InputLabel_Gmax = uilabel(app.SimulatedTreefallPatternTab);
            app.Catalog_InputLabel_Gmax.Text = 'Gmax';
            app.Catalog_InputLabel_alpha = uilabel(app.SimulatedTreefallPatternTab);
            app.Catalog_InputLabel_alpha.Text = 'alpha (degree)';
            app.Catalog_InputLabel_RMW = uilabel(app.SimulatedTreefallPatternTab);
            app.Catalog_InputLabel_RMW.Text = 'RMW (m)';
            app.Catalog_InputLabel_phi = uilabel(app.SimulatedTreefallPatternTab);
            app.Catalog_InputLabel_phi.Text = 'phi';
            app.Catalog_InputLabel_Vc = uilabel(app.SimulatedTreefallPatternTab);
            app.Catalog_InputLabel_Vc.Text = 'Vcrit (m/s)';
            app.StartEstimationButton_Catalog = uibutton(app.SimulatedTreefallPatternTab, 'push');
            app.StartEstimationButton_Catalog.ButtonPushedFcn = createCallbackFcn(app, @StartEstimationButton_CatalogPushed, true);
            app.StartEstimationButton_Catalog.Text = 'Start Estimation';
            app.Catalog_PreviousPatternsButton = uibutton(app.SimulatedTreefallPatternTab, 'push');
            app.Catalog_PreviousPatternsButton.ButtonPushedFcn = createCallbackFcn(app, @Catalog_PreviousPatternsButtonPushed, true);
            app.Catalog_PreviousPatternsButton.Text = 'Previous Patterns';
            app.Catalog_NextPatternsButton = uibutton(app.SimulatedTreefallPatternTab, 'push');
            app.Catalog_NextPatternsButton.ButtonPushedFcn = createCallbackFcn(app, @Catalog_NextPatternsButtonPushed, true);
            app.Catalog_NextPatternsButton.Text = 'Next Patterns';
            app.Catalog_Vtrans_ub = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_Vtrans_ub.ValueDisplayFormat = '%.1f';
            app.Catalog_Vtrans_ub.Value = 30;
            app.UBLabel_20 = uilabel(app.SimulatedTreefallPatternTab);
            app.UBLabel_20.Text = 'UB:';
            app.Catalog_Vtrans_lb = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_Vtrans_lb.ValueDisplayFormat = '%.1f';
            app.Catalog_Vtrans_lb.Value = 5;
            app.LBLabel_20 = uilabel(app.SimulatedTreefallPatternTab);
            app.LBLabel_20.Text = 'LB:';
            app.Catalog_gmax_ub = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_gmax_ub.ValueDisplayFormat = '%.2f';
            app.Catalog_gmax_ub.Value = 6;
            app.UBLabel_21 = uilabel(app.SimulatedTreefallPatternTab);
            app.UBLabel_21.Text = 'UB:';
            app.Catalog_gmax_lb = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_gmax_lb.ValueDisplayFormat = '%.2f';
            app.Catalog_gmax_lb.Value = 0.5;
            app.LBLabel_21 = uilabel(app.SimulatedTreefallPatternTab);
            app.LBLabel_21.Text = 'LB:';
            app.Catalog_alpha_ub = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_alpha_ub.ValueDisplayFormat = '%.1f';
            app.Catalog_alpha_ub.Value = 70;
            app.UBLabel_22 = uilabel(app.SimulatedTreefallPatternTab);
            app.UBLabel_22.Text = 'UB:';
            app.Catalog_alpha_lb = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_alpha_lb.ValueDisplayFormat = '%.1f';
            app.LBLabel_22 = uilabel(app.SimulatedTreefallPatternTab);
            app.LBLabel_22.Text = 'LB:';
            app.Catalog_rmax_ub = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_rmax_ub.ValueDisplayFormat = '%.1f';
            app.Catalog_rmax_ub.Value = 350;
            app.UBLabel_23 = uilabel(app.SimulatedTreefallPatternTab);
            app.UBLabel_23.Text = 'UB:';
            app.Catalog_rmax_lb = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_rmax_lb.ValueDisplayFormat = '%.1f';
            app.Catalog_rmax_lb.Value = 2.5;
            app.LBLabel_23 = uilabel(app.SimulatedTreefallPatternTab);
            app.LBLabel_23.Text = 'LB:';
            app.Catalog_phi_ub = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_phi_ub.ValueDisplayFormat = '%.2f';
            app.Catalog_phi_ub.Value = 1;
            app.UBLabel_24 = uilabel(app.SimulatedTreefallPatternTab);
            app.UBLabel_24.Text = 'UB:';
            app.Catalog_phi_lb = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_phi_lb.ValueDisplayFormat = '%.2f';
            app.Catalog_phi_lb.Value = 0.5;
            app.LBLabel_24 = uilabel(app.SimulatedTreefallPatternTab);
            app.LBLabel_24.Text = 'LB:';
            app.UBLabel_25 = uilabel(app.SimulatedTreefallPatternTab);
            app.UBLabel_25.Text = 'UB:';
            app.Catalog_Vc_ub = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_Vc_ub.ValueDisplayFormat = '%.1f';
            app.Catalog_Vc_ub.Value = 60;
            app.LBLabel_25 = uilabel(app.SimulatedTreefallPatternTab);
            app.LBLabel_25.Text = 'LB:';
            app.Catalog_Vc_lb = uieditfield(app.SimulatedTreefallPatternTab, 'numeric');
            app.Catalog_Vc_lb.ValueDisplayFormat = '%.1f';
            app.Catalog_Vc_lb.Value = 10;
            app.BestMatchParametersTab = uitab(app.CatalogMethod);
            app.BestMatchParametersTab.Title = 'Best-Match Parameters';
            app.UITable_Catalog = uitable(app.BestMatchParametersTab);
            app.UITable_Catalog.ColumnName = {'No'; 'Error'; 'Vtrans (m/s)'; 'Gmax'; 'alpha (degree)'; 'RMW (m)'; 'phi'; 'Vcrit (m/s)'; 'Vmax (m/s)'};
            app.UITable_Catalog.RowName = {};
            app.UITable_Catalog.CellSelectionCallback = createCallbackFcn(app, @UITable_CatalogCellSelection, true);
            app.TransectEditFieldLabel = uilabel(app.BestMatchParametersTab);
            app.TransectEditFieldLabel.Text = 'Transect #';
            app.TransectNum_Catalog = uieditfield(app.BestMatchParametersTab, 'numeric');
            app.TransectNum_Catalog.Limits = [1 Inf];
            app.TransectNum_Catalog.Value = 1;
            app.ImportParametersCatalogButton = uibutton(app.BestMatchParametersTab, 'push');
            app.ImportParametersCatalogButton.ButtonPushedFcn = createCallbackFcn(app, @ImportParametersCatalogButtonPushed, true);
            app.ImportParametersCatalogButton.Text = 'Export Parameters';
            app.ExportTableButton_Catalog = uibutton(app.BestMatchParametersTab, 'push');
            app.ExportTableButton_Catalog.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButton_CatalogPushed, true);
            app.ExportTableButton_Catalog.Text = 'Export Table';
            app.TransectMethodTab = uitab(app.TabGroup);
            app.TransectMethodTab.Title = 'Transect Method';
            app.TransectMethod = uitabgroup(app.TransectMethodTab);
            app.SimulatedTreefallPatternTab_2 = uitab(app.TransectMethod);
            app.SimulatedTreefallPatternTab_2.Title = 'Simulated Tree-fall Pattern';
            app.UIAxes7_5 = uiaxes(app.SimulatedTreefallPatternTab_2);
            app.UIAxes7_4 = uiaxes(app.SimulatedTreefallPatternTab_2);
            app.UIAxes7_3 = uiaxes(app.SimulatedTreefallPatternTab_2);
            app.UIAxes7_2 = uiaxes(app.SimulatedTreefallPatternTab_2);
            app.UIAxes7_1 = uiaxes(app.SimulatedTreefallPatternTab_2);
            ylabel(app.UIAxes7_1, 'Y distance (m)')
            app.Trans_InputLabel_Bounds = uilabel(app.SimulatedTreefallPatternTab_2);
            app.Trans_InputLabel_Bounds.Text = 'Enter bounds of each parameter (Top row: upperbound, bottom row: lowerbound)';
            app.Trans_InputLabel_Vtrans = uilabel(app.SimulatedTreefallPatternTab_2);
            app.Trans_InputLabel_Vtrans.Text = 'Vtrans (m/s)';
            app.Trans_InputLabel_Gmax = uilabel(app.SimulatedTreefallPatternTab_2);
            app.Trans_InputLabel_Gmax.Text = 'Gmax';
            app.Trans_InputLabel_alpha = uilabel(app.SimulatedTreefallPatternTab_2);
            app.Trans_InputLabel_alpha.Text = 'alpha (degree)';
            app.Trans_InputLabel_RMW = uilabel(app.SimulatedTreefallPatternTab_2);
            app.Trans_InputLabel_RMW.Text = 'RMW (m)';
            app.Trans_InputLabel_phi = uilabel(app.SimulatedTreefallPatternTab_2);
            app.Trans_InputLabel_phi.Text = 'phi';
            app.Trans_InputLabel_Vc = uilabel(app.SimulatedTreefallPatternTab_2);
            app.Trans_InputLabel_Vc.Text = 'Vcrit (m/s)';
            app.StartEstimationButton_Trans = uibutton(app.SimulatedTreefallPatternTab_2, 'push');
            app.StartEstimationButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @StartEstimationButton_TransPushed, true);
            app.StartEstimationButton_Trans.Text = 'Start Estimation';
            app.Trans_PreviousPatternsButton = uibutton(app.SimulatedTreefallPatternTab_2, 'push');
            app.Trans_PreviousPatternsButton.ButtonPushedFcn = createCallbackFcn(app, @Trans_PreviousPatternsButtonPushed, true);
            app.Trans_PreviousPatternsButton.Text = 'Previous Patterns';
            app.Trans_NextPatternsButton = uibutton(app.SimulatedTreefallPatternTab_2, 'push');
            app.Trans_NextPatternsButton.ButtonPushedFcn = createCallbackFcn(app, @Trans_NextPatternsButtonPushed, true);
            app.Trans_NextPatternsButton.Text = 'Next Patterns';
            app.UBEditFieldLabel = uilabel(app.SimulatedTreefallPatternTab_2);
            app.UBEditFieldLabel.Text = 'UB:';
            app.Trans_Vtrans_ub = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_Vtrans_ub.ValueDisplayFormat = '%.1f';
            app.Trans_Vtrans_ub.Value = 40;
            app.LBEditFieldLabel = uilabel(app.SimulatedTreefallPatternTab_2);
            app.LBEditFieldLabel.Text = 'LB:';
            app.Trans_Vtrans_lb = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_Vtrans_lb.ValueDisplayFormat = '%.1f';
            app.Trans_Vtrans_lb.Value = 5;
            app.gsEditFieldLabel_2 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.gsEditFieldLabel_2.Text = 'gs:';
            app.Trans_Vtrans_gs = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_Vtrans_gs.ValueDisplayFormat = '%.1f';
            app.Trans_Vtrans_gs.Value = 2.5;
            app.UBEditFieldLabel_2 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.UBEditFieldLabel_2.Text = 'UB:';
            app.Trans_gmax_ub = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_gmax_ub.ValueDisplayFormat = '%.2f';
            app.Trans_gmax_ub.Value = 7;
            app.LBEditFieldLabel_2 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.LBEditFieldLabel_2.Text = 'LB:';
            app.Trans_gmax_lb = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_gmax_lb.ValueDisplayFormat = '%.2f';
            app.Trans_gmax_lb.Value = 0.5;
            app.gsEditFieldLabel_3 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.gsEditFieldLabel_3.Text = 'gs:';
            app.Trans_gmax_gs = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_gmax_gs.ValueDisplayFormat = '%.2f';
            app.Trans_gmax_gs.Value = 0.25;
            app.UBEditFieldLabel_3 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.UBEditFieldLabel_3.Text = 'UB:';
            app.Trans_alpha_ub = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_alpha_ub.ValueDisplayFormat = '%.1f';
            app.Trans_alpha_ub.Value = 90;
            app.LBLabel_26 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.LBLabel_26.Text = 'LB:';
            app.Trans_alpha_lb = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_alpha_lb.ValueDisplayFormat = '%.1f';
            app.gsLabel = uilabel(app.SimulatedTreefallPatternTab_2);
            app.gsLabel.Text = 'gs:';
            app.Trans_alpha_gs = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_alpha_gs.ValueDisplayFormat = '%.1f';
            app.Trans_alpha_gs.Value = 5;
            app.UBEditFieldLabel_4 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.UBEditFieldLabel_4.Text = 'UB:';
            app.Trans_rmax_ub = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_rmax_ub.ValueDisplayFormat = '%.1f';
            app.Trans_rmax_ub.Value = 400;
            app.LBLabel_27 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.LBLabel_27.Text = 'LB:';
            app.Trans_rmax_lb = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_rmax_lb.ValueDisplayFormat = '%.1f';
            app.Trans_rmax_lb.Value = 2.5;
            app.gsLabel_2 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.gsLabel_2.Text = 'gs:';
            app.Trans_rmax_gs = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_rmax_gs.ValueDisplayFormat = '%.1f';
            app.Trans_rmax_gs.Value = 2.5;
            app.UBEditFieldLabel_5 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.UBEditFieldLabel_5.Text = 'UB:';
            app.Trans_phi_ub = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_phi_ub.Limits = [0 Inf];
            app.Trans_phi_ub.ValueDisplayFormat = '%.2f';
            app.Trans_phi_ub.Value = 1;
            app.LBLabel_28 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.LBLabel_28.Text = 'LB:';
            app.Trans_phi_lb = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_phi_lb.ValueDisplayFormat = '%.2f';
            app.Trans_phi_lb.Value = 0.5;
            app.gsLabel_3 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.gsLabel_3.Text = 'gs:';
            app.Trans_phi_gs = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_phi_gs.ValueDisplayFormat = '%.2f';
            app.Trans_phi_gs.Value = 0.05;
            app.UBEditFieldLabel_6 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.UBEditFieldLabel_6.Text = 'UB:';
            app.Trans_Vc_ub = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_Vc_ub.Limits = [0 Inf];
            app.Trans_Vc_ub.ValueDisplayFormat = '%.1f';
            app.Trans_Vc_ub.Value = 65;
            app.LBLabel_29 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.LBLabel_29.Text = 'LB:';
            app.Trans_Vc_lb = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_Vc_lb.ValueDisplayFormat = '%.1f';
            app.Trans_Vc_lb.Value = 5;
            app.gsLabel_4 = uilabel(app.SimulatedTreefallPatternTab_2);
            app.gsLabel_4.Text = 'gs:';
            app.Trans_Vc_gs = uieditfield(app.SimulatedTreefallPatternTab_2, 'numeric');
            app.Trans_Vc_gs.ValueDisplayFormat = '%.1f';
            app.Trans_Vc_gs.Value = 2.5;
            app.BestMatchParametersTab_2 = uitab(app.TransectMethod);
            app.BestMatchParametersTab_2.Title = 'Best-Match Parameters';
            app.UITable_Trans = uitable(app.BestMatchParametersTab_2);
            app.UITable_Trans.ColumnName = {'No'; 'Error'; 'Vtrans (m/s)'; 'Gmax'; 'alpha (degree)'; 'RMW (m)'; 'phi'; 'Vcrit (m/s)'; 'Vmax (m/s)'};
            app.UITable_Trans.RowName = {};
            app.UITable_Trans.CellSelectionCallback = createCallbackFcn(app, @UITable_TransCellSelection, true);
            app.TransectEditFieldLabel_2 = uilabel(app.BestMatchParametersTab_2);
            app.TransectEditFieldLabel_2.Text = 'Transect #';
            app.TransectNum_Trans = uieditfield(app.BestMatchParametersTab_2, 'numeric');
            app.TransectNum_Trans.Limits = [1 Inf];
            app.TransectNum_Trans.Value = 1;
            app.ImportParametersTransButton = uibutton(app.BestMatchParametersTab_2, 'push');
            app.ImportParametersTransButton.ButtonPushedFcn = createCallbackFcn(app, @ImportParametersTransButtonPushed, true);
            app.ImportParametersTransButton.Text = 'Export Parameters';
            app.ExportTableButton_Trans = uibutton(app.BestMatchParametersTab_2, 'push');
            app.ExportTableButton_Trans.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButton_TransPushed, true);
            app.ExportTableButton_Trans.Text = 'Export Table';
            app.AnalyticalMethodTab = uitab(app.TabGroup);
            app.AnalyticalMethodTab.Title = 'Analytical Method';
            app.TabGroup4 = uitabgroup(app.AnalyticalMethodTab);
            app.BestFitParameterEstimationTab = uitab(app.TabGroup4);
            app.BestFitParameterEstimationTab.Title = 'Best-Fit Parameter Estimation';
            app.StartEstimationButton_Anal = uibutton(app.BestFitParameterEstimationTab, 'push');
            app.StartEstimationButton_Anal.ButtonPushedFcn = createCallbackFcn(app, @StartEstimationButton_AnalPushed, true);
            app.StartEstimationButton_Anal.Text = 'Start Estimation';
            app.Anal_InputLabel = uilabel(app.BestFitParameterEstimationTab);
            app.Anal_InputLabel.Text = 'Enter number of iteration for optimization';
            app.Anal_InputLabel_Bounds = uilabel(app.BestFitParameterEstimationTab);
            app.Anal_InputLabel_Bounds.Text = 'Enter bounds of each parameter (Top row: upperbound, bottom row: lowerbound)';
            app.Anal_InputLabel_eta = uilabel(app.BestFitParameterEstimationTab);
            app.Anal_InputLabel_eta.Text = 'eta';
            app.Anal_InputLabel_Gmax = uilabel(app.BestFitParameterEstimationTab);
            app.Anal_InputLabel_Gmax.Text = 'Gmax';
            app.Anal_InputLabel_alpha = uilabel(app.BestFitParameterEstimationTab);
            app.Anal_InputLabel_alpha.Text = 'alpha (degree)';
            app.Anal_InputLabel_RMW = uilabel(app.BestFitParameterEstimationTab);
            app.Anal_InputLabel_RMW.Text = 'RMW (m)';
            app.Anal_InputLabel_phi = uilabel(app.BestFitParameterEstimationTab);
            app.Anal_InputLabel_phi.Text = 'phi';
            app.ObservedTreefallPatternTypeLabel = uilabel(app.BestFitParameterEstimationTab);
            app.ObservedTreefallPatternTypeLabel.Text = 'Observed Tree-fall Pattern Type';
            app.ObservedTreefallPatternTypeSwitch = uiswitch(app.BestFitParameterEstimationTab, 'slider');
            app.ObservedTreefallPatternTypeSwitch.Items = {'Not-averaged', 'Averaged'};
            app.ObservedTreefallPatternTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @ObservedTreefallPatternTypeSwitchValueChanged, true);
            app.ObservedTreefallPatternTypeSwitch.Value = 'Not-averaged';
            app.NumberofIterationLabel = uilabel(app.BestFitParameterEstimationTab);
            app.NumberofIterationLabel.Text = 'Number of Iteration:';
            app.NumIteration = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.NumIteration.ValueDisplayFormat = '%.0f';
            app.NumIteration.Value = 1000000;
            app.BestFitDiagraminLBetaSpacePanel = uipanel(app.BestFitParameterEstimationTab);
            app.BestFitDiagraminLBetaSpacePanel.Title = 'Best-Fit Diagram in L-Beta Space';
            app.UIAxes5 = uiaxes(app.BestFitDiagraminLBetaSpacePanel);
            xlabel(app.UIAxes5, 'Beta (Degree)')
            ylabel(app.UIAxes5, 'L (m)')
            zlabel(app.UIAxes5, 'Z')
            app.Anal_NextPatternButton = uibutton(app.BestFitDiagraminLBetaSpacePanel, 'push');
            app.Anal_NextPatternButton.ButtonPushedFcn = createCallbackFcn(app, @Anal_NextPatternButtonPushed, true);
            app.Anal_NextPatternButton.Visible = 'off';
            app.Anal_NextPatternButton.Text = 'Next Pattern';
            app.Anal_PreviousPatternButton = uibutton(app.BestFitDiagraminLBetaSpacePanel, 'push');
            app.Anal_PreviousPatternButton.ButtonPushedFcn = createCallbackFcn(app, @Anal_PreviousPatternButtonPushed, true);
            app.Anal_PreviousPatternButton.Visible = 'off';
            app.Anal_PreviousPatternButton.Text = 'Previous Pattern';
            app.TreefallPatternPanel = uipanel(app.BestFitParameterEstimationTab);
            app.TreefallPatternPanel.Title = 'Tree-fall Pattern';
            app.UIAxes_GC_Obs = uiaxes(app.TreefallPatternPanel);
            xlabel(app.UIAxes_GC_Obs, 'X distance (m)')
            ylabel(app.UIAxes_GC_Obs, 'Y distance (m)')
            app.UBEditFieldLabel_7 = uilabel(app.BestFitParameterEstimationTab);
            app.UBEditFieldLabel_7.Text = 'UB:';
            app.Anal_eta_ub = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_eta_ub.ValueDisplayFormat = '%.2f';
            app.Anal_eta_ub.Value = 3;
            app.LBEditFieldLabel_3 = uilabel(app.BestFitParameterEstimationTab);
            app.LBEditFieldLabel_3.Text = 'LB:';
            app.Anal_eta_lb = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_eta_lb.ValueDisplayFormat = '%.2f';
            app.Anal_eta_lb.Value = 1;
            app.UBEditField_2Label = uilabel(app.BestFitParameterEstimationTab);
            app.UBEditField_2Label.Text = 'UB:';
            app.Anal_gmax_ub = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_gmax_ub.ValueDisplayFormat = '%.1f';
            app.Anal_gmax_ub.Value = 6;
            app.LBEditField_2Label = uilabel(app.BestFitParameterEstimationTab);
            app.LBEditField_2Label.Text = 'LB:';
            app.Anal_gmax_lb = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_gmax_lb.ValueDisplayFormat = '%.1f';
            app.Anal_gmax_lb.Value = 0.5;
            app.UBEditField_3Label = uilabel(app.BestFitParameterEstimationTab);
            app.UBEditField_3Label.Text = 'UB:';
            app.Anal_alpha_ub = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_alpha_ub.ValueDisplayFormat = '%.1f';
            app.Anal_alpha_ub.Value = 40;
            app.LBEditField_3Label = uilabel(app.BestFitParameterEstimationTab);
            app.LBEditField_3Label.Text = 'LB:';
            app.Anal_alpha_lb = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_alpha_lb.ValueDisplayFormat = '%.1f';
            app.UBEditField_4Label = uilabel(app.BestFitParameterEstimationTab);
            app.UBEditField_4Label.Text = 'UB:';
            app.Anal_rmax_ub = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_rmax_ub.ValueDisplayFormat = '%.1f';
            app.Anal_rmax_ub.Value = 200;
            app.LBEditField_4Label = uilabel(app.BestFitParameterEstimationTab);
            app.LBEditField_4Label.Text = 'LB:';
            app.Anal_rmax_lb = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_rmax_lb.ValueDisplayFormat = '%.1f';
            app.Anal_rmax_lb.Value = 50;
            app.UBEditField_5Label = uilabel(app.BestFitParameterEstimationTab);
            app.UBEditField_5Label.Text = 'UB:';
            app.Anal_phi_ub = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_phi_ub.ValueDisplayFormat = '%.2f';
            app.Anal_phi_ub.Value = 1;
            app.LBEditField_5Label = uilabel(app.BestFitParameterEstimationTab);
            app.LBEditField_5Label.Text = 'LB:';
            app.Anal_phi_lb = uieditfield(app.BestFitParameterEstimationTab, 'numeric');
            app.Anal_phi_lb.ValueDisplayFormat = '%.2f';
            app.Anal_phi_lb.Value = 0.5;
            app.BestFitParametersTab = uitab(app.TabGroup4);
            app.BestFitParametersTab.Title = 'Best-Fit Parameters';
            app.UITable_Analytical = uitable(app.BestFitParametersTab);
            app.UITable_Analytical.ColumnName = {'No'; 'Error'; 'Eta'; 'Gmax'; 'alpha (degree)'; 'RMW (m)'; 'phi'; 'Vcrit (m/s)'; 'Vmax (m/s)'};
            app.UITable_Analytical.RowName = {};
            app.UITable_Analytical.CellSelectionCallback = createCallbackFcn(app, @UITable_AnalyticalCellSelection, true);
            app.TransectEditField_2Label = uilabel(app.BestFitParametersTab);
            app.TransectEditField_2Label.Text = 'Transect #';
            app.TransectNum_Anal = uieditfield(app.BestFitParametersTab, 'numeric');
            app.TransectNum_Anal.Limits = [1 Inf];
            app.TransectNum_Anal.Value = 1;
            app.ImportParametersAnalButton = uibutton(app.BestFitParametersTab, 'push');
            app.ImportParametersAnalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportParametersAnalButtonPushed, true);
            app.ImportParametersAnalButton.Text = 'Export Parameters';
            app.VtransmsEditFieldLabel = uilabel(app.BestFitParametersTab);
            app.VtransmsEditFieldLabel.Text = 'Vtrans (m/s)';
            app.VtransAnalEditField = uieditfield(app.BestFitParametersTab, 'numeric');
            app.VtransAnalEditField.Limits = [0 Inf];
            app.VtransAnalEditField.ValueChangedFcn = createCallbackFcn(app, @VtransAnalEditFieldValueChanged, true);
            app.ExportTableButton_Anal = uibutton(app.BestFitParametersTab, 'push');
            app.ExportTableButton_Anal.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButton_AnalPushed, true);
            app.ExportTableButton_Anal.Text = 'Export Table';
            app.TreeCropModuleTab = uitab(app.TabGroup);
            app.TreeCropModuleTab.Title = 'Tree/Crop Module';
            app.CriticalWindSpeedofCornfallPanel = uipanel(app.TreeCropModuleTab);
            app.CriticalWindSpeedofCornfallPanel.Title = 'Critical Wind Speed of Corn-fall';
            app.UIAxes_VcCorn = uiaxes(app.CriticalWindSpeedofCornfallPanel);
            xlabel(app.UIAxes_VcCorn, 'Growth Stage')
            ylabel(app.UIAxes_VcCorn, 'Critical Wind Speed (m/s)')
            app.CornGrowthStageLabel = uilabel(app.CriticalWindSpeedofCornfallPanel);
            app.CornGrowthStageLabel.Text = 'Corn Growth Stage';
            app.CornGrowthStageSlider = uislider(app.CriticalWindSpeedofCornfallPanel);
            app.CornGrowthStageSlider.Limits = [1 8];
            app.CornGrowthStageSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.CornGrowthStageSlider.MajorTickLabels = {'V9-10', 'V12', 'V13', 'R1-2', 'R3-4', 'R4-5', 'R5-6', 'R6'};
            app.CornGrowthStageSlider.ValueChangedFcn = createCallbackFcn(app, @CornGrowthStageSliderValueChanged, true);
            app.CornGrowthStageSlider.MinorTicks = [];
            app.CornGrowthStageSlider.Value = 1;
            app.MeanVcCornLabel = uilabel(app.CriticalWindSpeedofCornfallPanel);
            app.MeanVcCornLabel.Text = 'Mean Vcrit:';
            app.StdVcCornLabel = uilabel(app.CriticalWindSpeedofCornfallPanel);
            app.StdVcCornLabel.Text = 'Std Vcrit:';
            app.CriticalWindSpeedofTreefallButtonGroup = uibuttongroup(app.TreeCropModuleTab);
            app.CriticalWindSpeedofTreefallButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CriticalWindSpeedofTreefallButtonGroupSelectionChanged, true);
            app.CriticalWindSpeedofTreefallButtonGroup.Title = 'Critical Wind Speed of Tree-fall';
            app.UIAxes_VcTree = uiaxes(app.CriticalWindSpeedofTreefallButtonGroup);
            xlabel(app.UIAxes_VcTree, 'H.DBH^2 (m^3)')
            ylabel(app.UIAxes_VcTree, 'Critical Wind Speed (m/s)')
            app.UprootSaturatedButton = uitogglebutton(app.CriticalWindSpeedofTreefallButtonGroup);
            app.UprootSaturatedButton.Text = 'Uproot (Saturated)';
            app.UprootSaturatedButton.Value = true;
            app.UprootUnsaturatedButton = uitogglebutton(app.CriticalWindSpeedofTreefallButtonGroup);
            app.UprootUnsaturatedButton.Text = 'Uproot (Unsaturated)';
            app.StemBreakageKnot075Button = uitogglebutton(app.CriticalWindSpeedofTreefallButtonGroup);
            app.StemBreakageKnot075Button.Text = 'Stem Breakage (Knot = 0.75)';
            app.StemBreakageKnot10Button = uitogglebutton(app.CriticalWindSpeedofTreefallButtonGroup);
            app.StemBreakageKnot10Button.Text = 'Stem Breakage (Knot = 1.0)';
            app.FailureModeTreePropertyLabel = uilabel(app.CriticalWindSpeedofTreefallButtonGroup);
            app.FailureModeTreePropertyLabel.Text = 'Failure Mode/Tree Property';
            app.MeanVcTreeLabel = uilabel(app.CriticalWindSpeedofTreefallButtonGroup);
            app.MeanVcTreeLabel.Text = 'Mean Vcrit: ';
            app.StdHDBH2m3EditFieldLabel = uilabel(app.CriticalWindSpeedofTreefallButtonGroup);
            app.StdHDBH2m3EditFieldLabel.Text = 'Std H.DBH^2 (m^3)';
            app.StdHDBHEditField = uieditfield(app.CriticalWindSpeedofTreefallButtonGroup, 'numeric');
            app.StdHDBHEditField.ValueDisplayFormat = '%.2f';
            app.MeanHDBH2m3Label = uilabel(app.CriticalWindSpeedofTreefallButtonGroup);
            app.MeanHDBH2m3Label.Text = 'Mean H.DBH^2 (m^3)';
            app.MeanHDBHEditField = uieditfield(app.CriticalWindSpeedofTreefallButtonGroup, 'numeric');
            app.MeanHDBHEditField.ValueDisplayFormat = '%.2f';
            app.StdVcTreeLabel = uilabel(app.CriticalWindSpeedofTreefallButtonGroup);
            app.StdVcTreeLabel.Text = 'Std Vcrit: ';
            app.WindFieldTab = uitab(app.TabGroup);
            app.WindFieldTab.Title = 'Wind Field';
            app.WindFieldProductionPanel = uipanel(app.WindFieldTab);
            app.WindFieldProductionPanel.Title = 'Wind Field Production';
            app.UITable_Transects = uitable(app.WindFieldProductionPanel);
            app.UITable_Transects.ColumnName = {'Transect #'; 'Latitude'; 'Longitude'};
            app.UITable_Transects.RowName = {};
            app.UITable_Transects.ColumnEditable = [false true true];
            app.NumberofTransectsLabel = uilabel(app.WindFieldProductionPanel);
            app.NumberofTransectsLabel.Text = 'Number of Transects:';
            app.NumberofTransectsEditField = uieditfield(app.WindFieldProductionPanel, 'numeric');
            app.NumberofTransectsEditField.Limits = [0 Inf];
            app.NumberofTransectsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofTransectsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofTransectsEditFieldValueChanged, true);
            app.ShowtornadotrackButton = uibutton(app.WindFieldProductionPanel, 'push');
            app.ShowtornadotrackButton.ButtonPushedFcn = createCallbackFcn(app, @ShowtornadotrackButtonPushed, true);
            app.ShowtornadotrackButton.Text = 'Show tornado track';
            app.UITable_TorParameters = uitable(app.WindFieldProductionPanel);
            app.UITable_TorParameters.ColumnName = {'Transect #'; 'Vtrans'; 'Gmax'; 'alpha'; 'RMW'; 'phi'};
            app.UITable_TorParameters.RowName = {};
            app.UITable_TorParameters.ColumnEditable = [false true true true true true];
            app.GenerateWindFieldButton = uibutton(app.WindFieldProductionPanel, 'push');
            app.GenerateWindFieldButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWindFieldButtonPushed, true);
            app.GenerateWindFieldButton.Text = 'Generate Wind Field';
            app.ExcelSheetNumberEditFieldLabel = uilabel(app.WindFieldProductionPanel);
            app.ExcelSheetNumberEditFieldLabel.Text = 'Excel Sheet Number:';
            app.ExcelSheetNumberEditField_TC = uieditfield(app.WindFieldProductionPanel, 'numeric');
            app.ExcelSheetNumberEditField_TC.Limits = [1 Inf];
            app.ExcelSheetNumberEditField_TC.Value = 1;
            app.CurrentFileEditField_2Label = uilabel(app.WindFieldProductionPanel);
            app.CurrentFileEditField_2Label.Text = 'Current File:';
            app.CurrentFileEditField_TC = uieditfield(app.WindFieldProductionPanel, 'text');
            app.SelectFileButton_TC = uibutton(app.WindFieldProductionPanel, 'push');
            app.SelectFileButton_TC.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButton_TCPushed, true);
            app.SelectFileButton_TC.Text = 'Select File';
            app.LATITUDELabel = uilabel(app.WindFieldProductionPanel);
            app.LATITUDELabel.Text = 'LATITUDE:';
            app.TorTrackLAT_EditField = uieditfield(app.WindFieldProductionPanel, 'text');
            app.TorTrackLAT_EditField.Value = 'A1:A10000';
            app.LONGITUDELabel = uilabel(app.WindFieldProductionPanel);
            app.LONGITUDELabel.Text = 'LONGITUDE:';
            app.TorTrackLON_EditField = uieditfield(app.WindFieldProductionPanel, 'text');
            app.TorTrackLON_EditField.Value = 'B1:B10000';
            app.InputTorTrackLabel = uilabel(app.WindFieldProductionPanel);
            app.InputTorTrackLabel.Text = 'Enter the rows of tornado track in the format of (X0:X00000)';
            app.InputTorTransectsLabel = uilabel(app.WindFieldProductionPanel);
            app.InputTorTransectsLabel.Text = 'Enter the Latitudes and Longtitudes of transects';
            app.ParametersLabel = uilabel(app.WindFieldProductionPanel);
            app.ParametersLabel.Text = 'Selected Parameters';
            app.InputNumTransectsLabel = uilabel(app.WindFieldProductionPanel);
            app.InputNumTransectsLabel.Text = 'Enter the desired number of transects to analyze';
            app.TranslationModelLabel = uilabel(app.WindFieldProductionPanel);
            app.TranslationModelLabel.Text = 'Translation Model:';
            app.TranslationModelDropDown = uidropdown(app.WindFieldProductionPanel);
            app.TranslationModelDropDown.Items = {'Constant Translation', 'Decaying Translation'};
            app.TranslationModelDropDown.Value = 'Constant Translation';
            app.WindFieldModelDropDownLabel = uilabel(app.WindFieldProductionPanel);
            app.WindFieldModelDropDownLabel.Text = 'Wind Field Model:';
            app.WindFieldModelDropDown = uidropdown(app.WindFieldProductionPanel);
            app.WindFieldModelDropDown.Items = {'Rankine Vortex', 'Burgers-Rott Vortex', 'Sullivan Vortex', 'Modified Sullivan Vortex'};
            app.WindFieldModelDropDown.Value = 'Rankine Vortex';
            app.TornadoTrackPanel = uipanel(app.WindFieldTab);
            app.TornadoTrackPanel.Title = 'Tornado Track';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'LOGO WERL-01-Trans.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Treefallsoftware_v2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end